classdef FeatureExtractionPipeline < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        UploadFilterTab                 matlab.ui.container.Tab
        PreprocessingcompletedLamp      matlab.ui.control.Lamp
        PreprocessingcompletedLampLabel  matlab.ui.control.Label
        NormalizationaftersubstractingpropertiesCheckBox  matlab.ui.control.CheckBox
        SelectpreprocessingoptionsLabel  matlab.ui.control.Label
        yEditField_2                    matlab.ui.control.NumericEditField
        yEditField_2Label               matlab.ui.control.Label
        pEditField                      matlab.ui.control.NumericEditField
        pEditFieldLabel                 matlab.ui.control.Label
        NormalizationbeforesubstractingpropertiesCheckBox  matlab.ui.control.CheckBox
        PreprocessingButton             matlab.ui.control.Button
        UpperBoundEditField             matlab.ui.control.NumericEditField
        UpperBoundEditFieldLabel        matlab.ui.control.Label
        TruncationofSpectrumCheckBox    matlab.ui.control.CheckBox
        BaselineCorrectionCheckBox      matlab.ui.control.CheckBox
        LowerBoundEditField             matlab.ui.control.NumericEditField
        LowerBoundEditFieldLabel        matlab.ui.control.Label
        UploadRamanDataButton           matlab.ui.control.Button
        UIAxes4_2                       matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        OverviewspectraTab              matlab.ui.container.Tab
        PixelcoordinatesinpixLabel      matlab.ui.control.Label
        ThresholdUBEditField            matlab.ui.control.NumericEditField
        ThresholdUBEditFieldLabel       matlab.ui.control.Label
        ThresholdLBEditField            matlab.ui.control.NumericEditField
        ThresholdLBEditFieldLabel       matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        PlotIntensityButton             matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        SizeShapeTab                    matlab.ui.container.Tab
        SizeandshapecompletedLamp       matlab.ui.control.Lamp
        SizeandshapecompletedLampLabel  matlab.ui.control.Label
        PlotsegmentedbinarymapButton    matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        ThresholdSwitch                 matlab.ui.control.Switch
        ThresholdSwitchLabel            matlab.ui.control.Label
        PixelconnectivitySwitch         matlab.ui.control.Switch
        PixelconnectivitySwitchLabel    matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        PropertiescalcificationButton   matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        PlotBinarymapButton             matlab.ui.control.Button
        PlotIntensitymap960peakButton   matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        CarbonateContentTab             matlab.ui.container.Tab
        PropertiescalcificationButton_2  matlab.ui.control.Button
        CarbonatecontentcompletedLamp_2  matlab.ui.control.Lamp
        CarbonatecontentcompletedLamp_2Label  matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        VisualisecarbonatecontentButton  matlab.ui.control.Button
        CarbonatevisualisationinheatmapsButtonGroup  matlab.ui.container.ButtonGroup
        FWHM960manuallyButton           matlab.ui.control.RadioButton
        EditField_2                     matlab.ui.control.NumericEditField
        ThresholdRsquaredSwitch         matlab.ui.control.Switch
        ThresholdRsquaredSwitchLabel    matlab.ui.control.Label
        ThresholdRsquaredEditField      matlab.ui.control.NumericEditField
        corIntensity1070peakButton      matlab.ui.control.RadioButton
        corarea1070peakButton           matlab.ui.control.RadioButton
        Rsquared1070Button              matlab.ui.control.RadioButton
        normresidual1070Button          matlab.ui.control.RadioButton
        Ratiointensity1070960Button     matlab.ui.control.RadioButton
        Ratioarea1070960Button          matlab.ui.control.RadioButton
        Intensity1070peakButton         matlab.ui.control.RadioButton
        Area1070peakButton              matlab.ui.control.RadioButton
        Intensity960peakButton          matlab.ui.control.RadioButton
        SwitchvisualisationButton_3     matlab.ui.control.Button
        Area960peakButton               matlab.ui.control.RadioButton
        FWHM960lorentzianfitButton      matlab.ui.control.RadioButton
        BinarymapButton                 matlab.ui.control.RadioButton
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        ProteinContentTab               matlab.ui.container.Tab
        UITable4                        matlab.ui.control.Table
        PropertiescalcificationButton_4  matlab.ui.control.Button
        ProteinvisualisationinheatmapsButtonGroup  matlab.ui.container.ButtonGroup
        ratioCO32phe1Button             matlab.ui.control.RadioButton
        ratiophe1HapButton              matlab.ui.control.RadioButton
        ThresEditField_3                matlab.ui.control.NumericEditField
        ThresEditField_3Label           matlab.ui.control.Label
        corintensity1650Button          matlab.ui.control.RadioButton
        corarea1650Button               matlab.ui.control.RadioButton
        binary1650Button                matlab.ui.control.RadioButton
        rsquared1650Button              matlab.ui.control.RadioButton
        resnorm1650Button               matlab.ui.control.RadioButton
        intensity1650Button             matlab.ui.control.RadioButton
        area1650Button                  matlab.ui.control.RadioButton
        ThresEditField_2                matlab.ui.control.NumericEditField
        ThresEditField_2Label           matlab.ui.control.Label
        corintensity1445Button          matlab.ui.control.RadioButton
        corarea1445Button               matlab.ui.control.RadioButton
        binary1445Button                matlab.ui.control.RadioButton
        rsquared1445Button              matlab.ui.control.RadioButton
        resnorm1445Button               matlab.ui.control.RadioButton
        intensity1445Button             matlab.ui.control.RadioButton
        area1445Button                  matlab.ui.control.RadioButton
        corintensity1004Button          matlab.ui.control.RadioButton
        corarea1004Button               matlab.ui.control.RadioButton
        binary1004Button                matlab.ui.control.RadioButton
        ThresEditField                  matlab.ui.control.NumericEditField
        ThresEditFieldLabel             matlab.ui.control.Label
        rsquared1004Button              matlab.ui.control.RadioButton
        resnorm1004Button               matlab.ui.control.RadioButton
        SwitchvisualisationButton_2     matlab.ui.control.Button
        intensity1004Button             matlab.ui.control.RadioButton
        area1004Button                  matlab.ui.control.RadioButton
        ProteincontentcompletedLamp     matlab.ui.control.Lamp
        ProteincontentcompletedLampLabel  matlab.ui.control.Label
        VisiualiseproteincontentButton  matlab.ui.control.Button
        UIAxes12_3                      matlab.ui.control.UIAxes
        UIAxes12_2                      matlab.ui.control.UIAxes
        UIAxes12                        matlab.ui.control.UIAxes
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        CrystallinityMagnesiumContentTab  matlab.ui.container.Tab
        CrystallintymagnsiumcontentcompletedLamp  matlab.ui.control.Lamp
        CrystallintymagnsiumcontentcompletedLabel  matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        PropertiescalcificationButton_3  matlab.ui.control.Button
        VisualisecrystallinitymagnesiumcontentButton  matlab.ui.control.Button
        CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup  matlab.ui.container.ButtonGroup
        SwitchvisualisationButton       matlab.ui.control.Button
        InverseFWHM960manuallyButton    matlab.ui.control.RadioButton
        WvnCarbonateEditField           matlab.ui.control.NumericEditField
        WvnCarbonateEditFieldLabel      matlab.ui.control.Label
        corrShiftcarbonatepeakButton    matlab.ui.control.RadioButton
        corrXcoordinatecarbonateButton  matlab.ui.control.RadioButton
        ShiftcarbonatepeakButton        matlab.ui.control.RadioButton
        XcoordinatecarbonateButton      matlab.ui.control.RadioButton
        WvnPhosphateEditField           matlab.ui.control.NumericEditField
        WvnPhosphateEditFieldLabel      matlab.ui.control.Label
        ShiftphosphatepeakButton        matlab.ui.control.RadioButton
        XcoordinatephosphateButton      matlab.ui.control.RadioButton
        InverseFWHM960LorentzianfitButton  matlab.ui.control.RadioButton
        UIAxes9_2                       matlab.ui.control.UIAxes
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        ExportTab                       matlab.ui.container.Tab
        MultiplefilesinonefolderLabel   matlab.ui.control.Label
        OnefileLabel                    matlab.ui.control.Label
        ExportpropertiesmanuallyButton  matlab.ui.control.Button
        WhenPropertiescalcificationareallselectedbyhandLabel  matlab.ui.control.Label
        PipelinenotmanuallyselectingpropertiesLabel  matlab.ui.control.Label
        ExportpropertiesofpatientfolderButton  matlab.ui.control.Button
        ExportpropertiesofsinglefileButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        FileName % Filenmane of uploaded Raman raster scan
        CurrentFilePath % user can select single file by hand ('upload file') or folder can be selected which runs automatically ('export folder')
        
        RawData % raw uploaded Raman Data 1600x1601 matrix
        FilterData % uploaded Raman Data 1600x1601 matrix filtered on selected filters
        FilterDataBL % uploaded Raman Data 1600x1601 matrix filtered on baseline
        FilterDataTC % uploaded Raman Data 1600x1601 matrix filtered on trancute
        FilterDataNM % uploaded Raman Data 1600x1601 matrix filtered on normalisation
        totalareaspectra % a 40 x 40 matrix of total area under each spectrum, after filtering
        UnfiltAverageSpectra % a column vector containing the average raman spectrum of Raw uploaded Raman Data 1600x1601
        FiltAverageSpectra % a column vector containing the average raman spectrum of filtered uploaded Raman Data 1600x1601

        x_centers % a row vector representing the center positions of each pixel along the x-axis in micrometers
        y_centers % a row vector representing the center positions of each pixel along the y-axis in micrometers

        RawDataMatrix % raw uploaded Raman Data (RawData) in form of 40x40 cell array
        FilterDataMatrix % filtered Raman Data (FilterDataNM) in form of 40x40 cell array
        RamanMatrix960 % a 40x40 numeric array containing the average intensity values of the bandwidth 955-965 cm-1
        FilterRamanMatrix960 % a 40x40 numeric array containing the average intensity values of the bandwidth 955-965 cm-1 of the filtered spectra
       
        BinairyMatrix960 % binary 40x40 matrix based on thresholding of 960 peak

        Boundaries % a column vector with each row containing the coordinates of the exterior boundaries of an individual microcalcification.
        Label_relabel % a 1600x1600 matrix which labels the corresponding pixels of an object with same number, object is bigger than 3 pixels.
        stats_relabel % array of sturctures or a table containing properties for each labeled region (microcalcification) in label image Label_relabel.
        
        Lorentzcell960 % trancuted 1601x1600 matrix to only select wvns around 960 peak
        Lorentzmatr960 % trancuted 40x40 array with only intensities of wvns around 960 peak
        Result960 % 40×40 array where each pixel within 960 binairy map contains a trancuted spectrum, in other pixels all intensities of Raman spectrum are set to zero
        meancorr960 % 40×40 matrix with each element containing the total squared deviation from the mean of the Raman spectrum at that pixel (masked using Binairy_960)
        Lorentzfit960 % 40x40 cell array with each cell containing Lorentzian fit (wavenumber vs. intensity) to the spectrum of pixels within the binairy map
        Lorentzpara960 % 40x40 cell array with each cell containing fitted Lorentzian paramters for each pixel (amplitude, peak position, width factor)
        Lorentzbaseline960 % 40x40 cell array with baseline coordinates (wavenumber vs. intensity) for each pixel with Lorentzian fit
        FWHM960 % 40x40 matrix of FWHM values of 960 peak derived from Lorentzian fit, only for pixels in crystal
        cor_FWHM960 % 40x40 matrix of FWHM values of 960 peak derived from Lorentzian fit, only for pixels in crystal, with pixels having FWHM >= 40 being set to NaN
        FWHM960_hand % 40x40 matrix of FWHM values of 960 peak derived manually, corrected for baseline not being entirely equal at zero
        Intensity960 % 40x40 matrix of maximum intensity of fitted lorentzian curve (at 960 peak) at each pixel in crystal
        PeakPosition960 % 40x40 matrix of wvn position of maximum intensity of fitted orentzian curve (at 960 peak) at each pixel in crystal
        integral960 % 40x40 matrix of area between the Lorentzian 960 peak and baseline for each pixel in crystal
        resnorm960 % 40×40 matrix of residual norm values (sum of squared differences between lorentzian fit and original spectrum) for each pixel
        residual960 % 40x40 cell array of residual vectors (original spectrum minus Lorentzian fit) for each pixel in crystal

        nor_integral960 % 40x40 matrix of area between the Lorentzian 960 peak and baseline for each pixel in crystal, area-normalized by (integral960/totalareaspectra)
        nor_Intensity960 % 40x40 matrix of maximum intensity of fitted lorentzian curve (at 960 peak) at each pixel in crystal, area-normalized by (Intensity960/totalareaspectra)

        Lorentzcell1070 % trancuted 1601x1600 matrix to only select wvns around 1070 peak
        Lorentzmatr1070 % trancuted 40x40 array with only intensities of wvns around 1070 peak
        Result1070 % 40×40 array where each pixel within 1070 binairy map contains a trancuted spectrum, in other pixels all intensities of Raman spectrum are set to zero
        meancorr1070 % 40×40 cell array with each element containing the total squared deviation from the mean of the Raman spectrum at that pixel (masked using Binairy_960)
        Lorentzfit1070 % 40x40 cell array with each cell containing Lorentzian fit (wavenumber vs. intensity) to the spectrum of pixels within the binairy map
        Lorentzpara1070 % 40x40 cell arrat with each cell containing fitted Lorentzian paramters for each pixel (amplitude, peak position, width factor)
        Lorentzbaseline1070 % 40x40 cell array with baseline coordinates (wavenumber vs. intensity) fore each pixel with Lorentzian fit
        FWHM1070 % 40x40 matrix of FWHM values of 1070 peak derived from Lorentzian fit, only for pixels in crystal
        FWHM1070_hand % 40x40 matrix of FWHM values of 1070 peak derived manually, corrected for baseline not being entirely equal at zero
        Intensity1070 % 40x40 matrix of maximum intensity of fitted lorentzian curve (at 1070 peak) at each pixel in crystal
        PeakPosition1070 % 40x40 matrix of wvn position of maximum intensity of fitted Lorentzian curve (at 1070 peak) at each pixel in crystal
        integral1070 % 40x40 matrix of area between the Lorentzian 1070 peak and baseline for each pixel in crystal
        resnorm1070 % 40×40 matrix of residual norm values (sum of squared differences between lorentzian fit and original spectrum) for each pixel
        residual1070 % 40x40 cell array of residual vectors (original spectrum minus Lorentzian fit) for each pixel in crystal
        
        Rsquared % 40x40 matrix with R2 values for Lorentzian fit of 1070 peak (only for pixels within crystal): 1 = perfect fit, 0 = poor fit
        BinairyMatrix1070 % binary 40x40 matrix (mask) with pixels meeting the automatic area and rsquared threshold set to 1, used for carbonate analysis
        cor_integral1070 % 40x40 matrix with area of Lorentzian fit, filtered using binairy map 1070: NaN = low quality 1070 pixel
        cor_Intensity1070 % 40x40 matrix with peak intensity of Lorentzian fit, filtered using binairy map 1070: NaN = low quality 1070 pixel
        ratioarea % 40×40 matrix of the ratio between corrected integrals at 1070 and 960 peak (cor_integral1070 / integral960)
        ratiointensity % 40×40 matrix of the ratio between corrected intensities at 1070 and 960 peak (cor_intensity1070 / intensity960)

        nor_cor_integral1070 % 40x40 matrix with area of Lorentzian fit, filtered using binairy map 1070, area-normalized by (cor_integral1070/totalareaspectra)
        nor_cor_Intensity1070 % 40x40 matrix with peak intensity of Lorentzian fit, filtered using binairy map 1070, area-normalized by (cor_integral1070/totalareaspectra)

        InvFWHM960 % 40x40 matrix of the inverse of FWHM values of 960 peak derived from Lorentzian fit, only for pixels in crystal
        InvFWHM960_hand % 40x40 matrix of the inverse of FWHM values of 960 peak derived manually, only for pixels in crystal
        shift960 % 40x40 matrix of blue/red shift of maximum intensity of fitted Lorentzian curve (at 960 peak) (compared to wvn 960 cm-1)
        shift1070 % 40x40 matrix of blue/red shift of maximum intensity of fitted Lorentzian curve (at 1070 peak) (compared to wvn 1070 cm-1)
        cor_PeakPosition1070 % 40x40 matrix of wvn position of maximum intensity of fitted Lorentzian curve(at 1070 peak), corrected using 1070 mask, at each pixel in crystal
        cor_shift1070 % 40x40 matrix of blue/red shift of maximum intensity of fitted Lorentzian curve (at 1070 peak), corrected using 1070 mask, (compared to wvn 1070 cm-1)

        FilterBckMatrix % column vector of average Raman spectrum of all backgroundpixels (pixels where binairy 960 = 0)
        Cvalues % 40x40 matrix of all calculated C values to which average background fits to spectra of each pixel, only at each pixel in crystal
        bckcorrectedMatrix % 40×40 array where each pixel within 960 binairy map contains wavenumber and intensity values, corrected for background, other pixels intensities set to zero

        Lorentzcell1004 % Trancuted 1601x1600 matrix to only select wvns around 1004 peak
        Lorentzmatr1004 % Trancuted 40x40 array with only intensities of wvns around 1004 peak
        Result1004 % 40×40 array where each pixel within 1004 binairy map contains a trancuted spectrum, in other pixels all intensities of Raman spectrum are set to zero
        meancorr1004 % 40×40 cell array with each element containing the total squared deviation from the mean of the Raman spectrum at that pixel (masked using Binairy_960)
        Lorentzfit1004 % 40x40 cell array with each cell containing Lorentzian fit (wavenumber vs. intensity) to the spectrum of pixels within the binairy map
        Lorentzpara1004 % 40x40 cell arrat with each cell containing fitted Lorentzian paramters for each pixel (amplitude, peak position, width factor)
        Lorentzbaseline1004 % 40x40 cell array with baseline coordinates (wavenumber vs. intensity) fore each pixel with Lorentzian fit
        FWHM1004 % 40x40 matrix of FWHM values of 1004 peak derived from Lorentzian fit, only for pixels in crystal
        FWHM1004_hand % 40x40 matrix of FWHM values of 1004 peak derived manually, corrected for baseline not being entirely equal at zero
        Intensity1004 % 40x40 matrix of maximum intensity of fitted lorentzian curve (at 1004 peak) at each pixel in crystal
        PeakPosition1004 % 40x40 matrix of wvn position of maximum intensity of fitted orentzian curve (at 1004 peak) at each pixel in crystal
        integral1004 % 40x40 matrix of area between the Lorentzian 1004 peak and baseline for each pixel in crystal
        resnorm1004 % 40×40 matrix of residual norm values (sum of squared differences between lorentzian fit and original spectrum) for each pixel
        residual1004 % 40x40 cell array of residual vectors (original spectrum minus Lorentzian fit) for each pixel in crystal
        
        Rsquared1004 % 40x40 matrix with R2 values for Lorentzian fit of 1004 peak (only for pixels within crystal): 1 = perfect fit, 0 = poor fit
        BinairyMatrix1004 % binary 40x40 matrix (mask) with pixels meeting the automatic area and rsquared threshold set to 1, used for carbonate analysis
        cor_integral1004 % 40x40 matrix with area of Lorentzian fit, filtered using binairy map 1004
        cor_Intensity1004 % 40x40 matrix with peak intensity of Lorentzian fit, filtered using binairy map 1004

        nor_cor_integral1004 % 40x40 matrix with area of Lorentzian fit, filtered using binairy map 1004, area-normalized by (cor_integral1004/totalareaspectra)
        nor_cor_Intensity1004 % 40x40 matrix with peak intensity of Lorentzian fit, filtered using binairy map 1004, area-normalized by (cor_integral1004/totalareaspectra)

        Lorentzcell1445 % Trancuted 1601x1600 matrix to only select wvns around 1445 peak
        Lorentzmatr1445 % Trancuted 40x40 array with only intensities of wvns around 1445 peak
        Result1445 % 440×40 array where each pixel within 1445 binairy map contains a trancuted spectrum, in other pixels all intensities of Raman spectrum are set to zero
        meancorr1445 % 40×40 cell array with each element containing the total squared deviation from the mean of the Raman spectrum at that pixel (masked using Binairy_960)
        Lorentzfit1445 % 40x40 cell array with each cell containing Lorentzian fit (wavenumber vs. intensity) to the spectrum of pixels within the binairy map
        Lorentzpara1445 % 40x40 cell arrat with each cell containing fitted Lorentzian paramters for each pixel (amplitude, peak position, width factor)
        Lorentzbaseline1445 % 40x40 cell array with baseline coordinates (wavenumber vs. intensity) fore each pixel with Lorentzian fit
        FWHM1445 % 40x40 matrix of FWHM values of 1445 peak derived from Lorentzian fit, only for pixels in crystal
        FWHM1445_hand % 40x40 matrix of FWHM values of 1445 peak derived manually, corrected for baseline not being entirely equal at zero
        Intensity1445 % 40x40 matrix of maximum intensity of fitted lorentzian curve (at 1445 peak) at each pixel in crystal
        PeakPosition1445 % 40x40 matrix of wvn position of maximum intensity of fitted orentzian curve (at 1445 peak) at each pixel in crystal
        integral1445 % 40x40 matrix of area between the Lorentzian 1445 peak and baseline for each pixel in crystal
        resnorm1445 % 40×40 matrix of residual norm values (sum of squared differences between lorentzian fit and original spectrum) for each pixel
        residual1445% 40x40 cell array of residual vectors (original spectrum minus Lorentzian fit) for each pixel in crystal
        
        Rsquared1445 % 40x40 matrix with R2 values for Lorentzian fit of 1445 peak (only for pixels within crystal): 1 = perfect fit, 0 = poor fit
        BinairyMatrix1445 % binary 40x40 matrix (mask) with pixels meeting the automatic area and rsquared threshold set to 1, used for carbonate analysis
        cor_integral1445 % 40x40 matrix with area of Lorentzian fit, filtered using binairy map 1445
        cor_Intensity1445 % 40x40 matrix with peak intensity of Lorentzian fit, filtered using binairy map 1445

        nor_cor_integral1445 % 40x40 matrix with area of Lorentzian fit, filtered using binairy map 1445, area-normalized by (cor_integral1445/totalareaspectra)
        nor_cor_Intensity1445 % 40x40 matrix with peak intensity of Lorentzian fit, filtered using binairy map 1445, area-normalized by (cor_integral1445/totalareaspectra)

        Lorentzcell1650 % Trancuted 1601x1600 matrix to only select wvns around 1650 peak
        Lorentzmatr1650 % Trancuted 40x40 array with only intensities of wvns around 1650 peak
        Result1650 % 40×40 array where each pixel within 1650 binairy map contains a trancuted spectrum, in other pixels all intensities of Raman spectrum are set to zero
        meancorr1650 % 40×40 cell array with each element containing the total squared deviation from the mean of the Raman spectrum at that pixel (masked using Binairy_960)
        Lorentzfit1650 % 40x40 cell array with each cell containing Lorentzian fit (wavenumber vs. intensity) to the spectrum of pixels within the binairy map
        Lorentzpara1650 % 40x40 cell arrat with each cell containing fitted Lorentzian paramters for each pixel (amplitude, peak position, width factor)
        Lorentzbaseline1650 % 40x40 cell array with baseline coordinates (wavenumber vs. intensity) fore each pixel with Lorentzian fit
        FWHM1650 % 40x40 matrix of FWHM values of 1650 peak derived from Lorentzian fit, only for pixels in crystal
        FWHM1650_hand % 40x40 matrix of FWHM values of 1650 peak derived manually, corrected for baseline not being entirely equal at zero
        Intensity1650 % 40x40 matrix of maximum intensity of fitted lorentzian curve (at 1650 peak) at each pixel in crystal
        PeakPosition1650 % 40x40 matrix of wvn position of maximum intensity of fitted orentzian curve (at 1650 peak) at each pixel in crystal
        integral1650 % 40x40 matrix of area between the Lorentzian 1650 peak and baseline for each pixel in crystal
        resnorm1650 % 40×40 matrix of residual norm values (sum of squared differences between lorentzian fit and original spectrum) for each pixel
        residual1650 % 40x40 cell array of residual vectors (original spectrum minus Lorentzian fit) for each pixel in crystal
        
        Rsquared1650 % 40x40 matrix with R2 values for Lorentzian fit of 1650 peak (only for pixels within crystal): 1 = perfect fit, 0 = poor fit
        BinairyMatrix1650 % binary 40x40 matrix (mask) with pixels meeting the automatic area and rsquared threshold set to 1, used for carbonate analysis
        cor_integral1650 % 40x40 matrix with area of Lorentzian fit, filtered using binairy map 1650
        cor_Intensity1650 % 40x40 matrix with peak intensity of Lorentzian fit, filtered using binairy map 1650

        nor_cor_integral1650 % 40x40 matrix with area of Lorentzian fit, filtered using binairy map 1650, area-normalized by (cor_integral1650/totalareaspectra)
        nor_cor_Intensity1650 % 40x40 matrix with peak intensity of Lorentzian fit, filtered using binairy map 1650, area-normalized by (cor_integral1650/totalareaspectra)

        ratio_phe_hap % 40×40 matrix of the ratio between corrected intensities at 1004 and 960 peak (cor_Intensity1004 / Intensity960)
        ratio_CO32_phe % 40×40 matrix of the ratio between corrected intensities at 1070 and 1004 peak (cor_Intensity1070 / cor_Intensity1004)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadRamanDataButton
        function UploadRamanDataButtonPushed(app, event)
        % the user should select a single *.asc, *.txt or *.bin file when
        % 'Upload Raman Data' or 'Export properties of single file' is pushed. 
        % The user should select a folder when 'Export properties of a
        % patient folder' is pushed. 
        if isprop(app, 'CurrentFilePath') && ~isempty(app.CurrentFilePath)
            filePath = app.CurrentFilePath;
            [~, name, ext] = fileparts(filePath);
            FileName = [name, ext];
        else
            [file, path] = uigetfile({'*.asc'; '*.txt'; '*.bin'}, 'MultiSelect', 'off', 'Select the data');
            if isequal(file, 0)
                return; 
            end
            filePath = fullfile(path, file);
            FileName = file;
        end
        
        % data is loaded from a single *.asc, *.txt or *.bin file. When the
        % single file has been loaded, app.RawData should contain a
        % 1601x1600 matrix with the first column containing the wavenumber
        % axis and the following columns containing Raman spectra of the
        % pixels of raster scan.
        app.FileName = FileName;
        commapoint(char(filePath)); 
        app.RawData = load(char(filePath));

        % calculate and plot the average spectrum of the all the spectra in
        % RawData
        app.UnfiltAverageSpectra = AverageSpec(app.RawData);
        x = app.UnfiltAverageSpectra(:,1);
        y = app.UnfiltAverageSpectra(:,2);
        plot(app.UIAxes4,x,y);

        % reshape the 1601x1600 matrix into a 40x40 cell array 
        Cell = convertRawDataToCell(app.RawData);
        app.RawDataMatrix = Cell;

        % create a intensity map of the 960 peak throughout the crystal,
        % using bandwidth chosen by user in tab 'Overview spectra'
        [Cell_960, Cell_960avr] = selectbandwithcell(Cell,app.RawData,app.ThresholdLBEditField.Value,app.ThresholdUBEditField.Value);
        app.RamanMatrix960 = Cell_960; 

        % set all lights to red
        app.PreprocessingcompletedLamp.Color = 'red';
        app.SizeandshapecompletedLamp.Color = 'red';
        app.CarbonatecontentcompletedLamp_2.Color = 'red';
        app.ProteincontentcompletedLamp.Color = 'red';
        app.CrystallintymagnsiumcontentcompletedLamp.Color = 'red';

        end

        % Button pushed function: PreprocessingButton
        function PreprocessingButtonPushed(app, event)
            % apply whittaker smoother filtering when corresponding box in
            % tab 'Upload + filter' is checked. Parameters p and y can be
            % determined and changed by the user.
            app.FilterData = app.RawData;
            app.FiltAverageSpectra = AverageSpec(app.FilterData);
            if app.BaselineCorrectionCheckBox.Value == 1
                p = app.pEditField.Value;
                y = app.yEditField_2.Value;
                [data_filtered, baseline] = Whittaker_smoother(app.RawData(:,2:end),p,y);
                app.FilterDataBL = [app.RawData(:,1) data_filtered];
            else
                app.FilterDataBL = app.RawData;
            end

            % apply truncation when corresponding box in tab 'Upload + filter' 
            % is checked. Intensities corresponding to wavenumbers between
            % lower and upper bound are kept.
            if app.TruncationofSpectrumCheckBox.Value == 1
                Lower = app.LowerBoundEditField.Value;
                Upper = app.UpperBoundEditField.Value;
                app.FilterDataTC = trancutecell(app.FilterDataBL, Lower, Upper);
                xlim(app.UIAxes4_2, [Lower Upper]);
            else
                app.FilterDataTC = app.FilterDataBL;
                xlim(app.UIAxes4_2, 'auto');
            end

            % apply area normalization on the all Raman spectra before 40x40 matrixes of 
            % Raman spectral features are generated in tabs 'Carbonate content',
            % 'Protein content' and 'Crystallinty + magnesium content' (when corresponding box in
            % tab 'Upload + filter' is checked).
            if app.NormalizationbeforesubstractingpropertiesCheckBox.Value == 1
                columnSums = sum(app.FilterDataTC(:,2:end), 1);
                spectra_norm = app.FilterDataTC(:,2:end) ./ columnSums;
                app.FilterDataNM = [app.FilterDataTC(:,1), spectra_norm];
                app.totalareaspectra = ones(40, 40);
            else
                app.FilterDataNM = app.FilterDataTC;
                app.totalareaspectra = ones(40, 40);
            end

            % apply area normalization on the 40x40 matrixes of 
            % Raman spectral features when generated in tabs 'Carbonate content',
            % 'Protein content' and 'Crystallinty + magnesium content' (when corresponding box in
            % tab 'Upload + filter' is checked). Area normalization is
            % later applied and not directly on the Raman spectra.
            if app.NormalizationaftersubstractingpropertiesCheckBox.Value == 1
                columnSums = [NaN sum(app.FilterDataNM(:,2:end), 1)];
                normalization = convertRawDataToCell(columnSums);
                app.totalareaspectra = cell2mat(normalization);
            else
                app.totalareaspectra = ones(40, 40);
            end

            % calculate and plot the average spectrum of the all the spectra in
            % FilterData
            app.FilterData = app.FilterDataNM;
            app.FiltAverageSpectra = AverageSpec(app.FilterData);
            x = app.FiltAverageSpectra(:,1);
            y = app.FiltAverageSpectra(:,2);
            plot(app.UIAxes4_2,x,y);
            app.FilterDataMatrix = convertRawDataToCell(app.FilterData);

            app.PreprocessingcompletedLamp.Color = 'green';
            
        
        end

        % Button pushed function: PlotIntensityButton, 
        % ...and 1 other component
        function PlotIntensitymap960peakButtonPushed(app, event)
            % set 40x40 pixel axis into 56x56 um axis
            um_per_pixel = 56 / 40;
            x_edges = linspace(0, 56, 41);
            app.x_centers = x_edges(1:end-1) + um_per_pixel/2;
            app.y_centers = app.x_centers;
            
            % plot the intensity map of the 960 peak of unfiltered or
            % filtered 40x40 cell arrayin the tab 'Overview spectra'. 
            % User can choose between unfiltered or filtered data.
            value = app.Switch.Value;
            if strcmp(value, 'Filtered')
                [app.FilterRamanMatrix960, BWavr_filter960] = selectbandwithcell(convertRawDataToCell(app.FilterData), app.FilterData, app.ThresholdLBEditField.Value, app.ThresholdUBEditField.Value);
                imagesc(app.UIAxes_2, app.x_centers, app.y_centers, app.FilterRamanMatrix960, "HitTest", "off");
            else
                [app.RamanMatrix960, BWavr_960] = selectbandwithcell(convertRawDataToCell(app.RawData), app.RawData, app.ThresholdLBEditField.Value, app.ThresholdUBEditField.Value);
                imagesc(app.UIAxes_2, app.x_centers, app.y_centers, app.RamanMatrix960, "HitTest", "off");
            end
           
            axis(app.UIAxes_2, 'equal');
            xlim(app.UIAxes_2, [0 56]);
            ylim(app.UIAxes_2, [0 56]);
    
            c = colorbar(app.UIAxes_2); 
            colormap(app.UIAxes_2, turbo); 
    
            %plot intensity map of the 960 peak based on filtered 40x40
            %cell array in tab 'Size + Shape' 
            axis(app.UIAxes, 'equal');
            xlim(app.UIAxes, [0 56]);
            ylim(app.UIAxes, [0 56]);
            imagesc(app.UIAxes,app.x_centers, app.y_centers, app.FilterRamanMatrix960);
    
            c = colorbar(app.UIAxes); 
            colormap(app.UIAxes, turbo); 
    
            %plot intensity map of the 960 peak based on filtered 40x40
            %cell array in tab 'Protein content' 
            axis(app.UIAxes11, 'equal');
            xlim(app.UIAxes11, [0 56]);
            ylim(app.UIAxes11, [0 56]);
            imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.FilterRamanMatrix960,"HitTest","off");
    
            c = colorbar(app.UIAxes11); 
            colormap(app.UIAxes11, turbo); 
        
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            % enable user to click on pixel of intensity map of 960 peak 
            clickpoint = app.UIAxes_2.CurrentPoint;
            X_um = clickpoint(1,1);
            Y_um = clickpoint(1,2);
        
            um_per_pixel = 56 / 40;
            X = floor(X_um / um_per_pixel) + 1;
            Y = floor(Y_um / um_per_pixel) + 1;
        
            X = max(1, min(40, X));
            Y = max(1, min(40, Y));
        
            app.xEditField.Value = X;
            app.yEditField.Value = Y;

            % plot the Raman spectrum corresponding to selected pixel by the user.
            % Based on the switch, the filtered or unfiltered Raman spectrum
            % is plotted.
            value = app.Switch.Value;
            if strcmp(value,'Filtered')
                x = app.FilterData(:,1);
                y = app.FilterDataMatrix{Y,X};
            else
                x = app.RawData(:,1);
                y = app.RawDataMatrix{Y,X};
            end
        
            plot(app.UIAxes3, x, y);
        end

        % Button pushed function: PlotBinarymapButton
        function PlotBinarymapButtonPushed(app, event)
            % create binary map based on intensity map of 960 peak, using
            % an automic threshold (Otsu's method) or an threshold set by
            % the user.
            cla(app.UIAxes2)
            if strcmp(app.ThresholdSwitch.Value,'Manual')
                Binairy_960 = thresholdBinaryMatrix(app.FilterRamanMatrix960, app.ThresholdEditField.Value); %Threshold instellen
                app.BinairyMatrix960 = Binairy_960;
            else
                averagestemp = app.FilterRamanMatrix960;
                averages = averagestemp./max(max(averagestemp));
                threshold = graythresh(averages);
                averages(averages < threshold) = 0;
                averages(averages >= threshold) = 1;
                app.BinairyMatrix960 = averages;
                app.EditField.Value = threshold;
            end
           
            % plot the binary map in tab 'Size and shape'
            axis(app.UIAxes2, 'equal');
            xlim(app.UIAxes2, [0 56]);
            ylim(app.UIAxes2, [0 56]);
            imagesc(app.UIAxes2,app.x_centers, app.y_centers,app.BinairyMatrix960);


        end

        % Button pushed function: PlotsegmentedbinarymapButton
        function PlotsegmentedbinarymapButtonPushed(app, event)
        % create segmented binary map based on binary map of 960 peak in
        % order to separate overlapping crystals.
        binary = app.BinairyMatrix960;
        
        % watershed function sepeartes crystals with area bigger than 20
        % pixels. Smaller crystals (<20 pixels) are not segmented.
        CC = bwconncomp(binary);
        stats = regionprops(CC, 'Area')';
        min_arealarge = 20;
        areas = [stats.Area];
        large_crys = find(areas >= min_arealarge);
        small_crys = find(areas < min_arealarge);
        mask_large = ismember(labelmatrix(CC), large_crys);
        mask_small = ismember(labelmatrix(CC), small_crys);
       
        D = -bwdist(~mask_large);
        mask = imextendedmin(D, 1);
        D2 = imimposemin(D, mask);
        
        L = watershed(D2);
        split = mask_large;
        split(L == 0) = 0; 
        
        finalBinary = split | mask_small;
        
        % plot the segmented binary map in tab 'Size and shape'
        axis(app.UIAxes2, 'equal');
        xlim(app.UIAxes2, [0 56]);
        ylim(app.UIAxes2, [0 56]);
        imagesc(app.UIAxes2, app.x_centers, app.y_centers, finalBinary);
        title(app.UIAxes2, 'Segmented binary map (960 peak)');
        app.BinairyMatrix960 = finalBinary;

        end

        % Button pushed function: PropertiescalcificationButton
        function PropertiescalcificationButtonPushed(app, event)
            % pixel connectivity can be chosen by the user
            if strcmp(app.PixelconnectivitySwitch.Value,'4')
                connect = 4;
            else
                connect = 8;
            end

            % find microcalcifications within the (segmented) binary map
            % using the chosen pixel connectivity. Only microcalcifications
            % larger than 2 pixels are included.
            [B,L]=bwboundaries(app.BinairyMatrix960,connect,'noholes');
            stats = regionprops(L,'all');
            index = find([stats.Area] >= 3);
            stats = stats(index);
            B = B(index);
            L_filtered = ismember(L, index);
            L_relabel = bwlabel(L_filtered, connect);
            [B, L_relabel] = bwboundaries(L_relabel, connect, 'noholes');
            
            % extract area and perimeter for each individual
            % microcalcication using regionprops.
            stats = regionprops(L_relabel, 'all');
            app.Boundaries = B;
            app.Label_relabel = L_relabel;
            app.stats_relabel = stats;
         
            % boundaries of microcalcifications are plotted in binary map
            % to show the individual microcalcifications present in the
            % raster scan.
            % Code used from: https://nl.mathworks.com/help/images/ref/bwboundaries.html
            imagesc(app.UIAxes2, app.x_centers, app.y_centers, app.BinairyMatrix960);
            hold (app.UIAxes2, 'on');
            colors=['b' 'g' 'r' 'y' 'm' 'c'];
            for k=1:length(B)
                boundary = B{k};
                cidx = mod(k,length(colors))+1;
                um_per_pixel = 56 / 40;
                x_boundary_um = (boundary(:,2) - 1) * um_per_pixel;
                y_boundary_um = (boundary(:,1) - 1) * um_per_pixel;
                plot(app.UIAxes2, x_boundary_um, y_boundary_um, colors(cidx), 'LineWidth', 6);
                
                % Randomize label location
                rndRow = ceil(length(boundary) / (mod(rand * k, 7) + 1));
                col_px = boundary(rndRow, 2);
                row_px = boundary(rndRow, 1);
                col_um = (col_px - 1) * um_per_pixel;
                row_um = (row_px - 1) * um_per_pixel;
                h = text(app.UIAxes2, col_um + 1, row_um - 1, num2str(L_relabel(row_px, col_px)));
                set(h, 'Color', colors(cidx), 'FontSize', 18, 'FontWeight', 'bold');
            end

            % extract circularity for each microcalcifications
            % Code used from: https://nl.mathworks.com/matlabcentral/answers/457873-regionprops-circularity-value-1-and-0#answer_1198560
            for k = 1:length(stats)
                r_eq = stats(k).Perimeter / (2*pi) + 0.5;
                correction = (1 - (0.5 / r_eq))^2;
                stats(k).Circularity = min(stats(k).Circularity * correction, 1);
            end

            % extract Fourier descriptor, variance in distance from
            % centroid to boundaries (var) and max:min distance ratio from
            % centroid to boundaries (maxmin) for each microcalcification.
            % Code used from studies of Yang et al. 2021.
            % https://pubs.acs.org/doi/10.1021/acs.analchem.1c00522
            for k = 1:length(B)
                boundary = B{k};
                forup = 0;
                z = frdescp(boundary);
                znor = abs(z);
                dnor = znor./znor(2);
                for i = 1:length(dnor)
                    forup = dnor(i)/i+forup;
                end
                fordown = sum(dnor(:));
                F10 = forup/fordown;
                FF10(k) = forup/fordown;

                d =[];
                centroid = fliplr(stats(k).Centroid);
                for n=1:length(boundary)
                    d = [d,sum((centroid-boundary(n,:)).^2).^0.5];
                end
                var(k) = std(d)/mean(d);
                if min(d) == 0
                    maxmin(k) = NaN;
                else
                    maxmin(k) = max(d)/min(d);
                end
            end
            
            % display size and shape properties of each microcalcification
            % in the table. Rows represent the individual
            % microcalcifications and columns represent the properties.
            index = num2cell(1:length(stats))';
            area_p = num2cell([stats.Area]');
            area_um = num2cell([stats.Area]'*1.96);
            circularity = num2cell([stats.Circularity]');
            perimeters = num2cell([stats.Perimeter]');
            FD = num2cell(FF10');
            var_cen = num2cell(var');
            maxmin_cen =num2cell(maxmin');

            area_mean=mean([stats.Area]);
            area_std=std([stats.Area]);
            area_mean_um=mean([stats.Area]*1.96);
            area_std_um=std([stats.Area]*1.96); 
            circularity_mean=mean([stats.Circularity]);
            circularity_std=std([stats.Circularity]);
            perimeter_mean=mean([stats.Perimeter]);
            perimeter_std=std([stats.Perimeter]);
            F10_mean=mean(FF10);
            F10_std=std(FF10);
            var_cen_mean=mean(var);
            var_cen_std=std(var);
            maxmin_cen_mean=mean(maxmin);
            maxmin_cen_std=std(maxmin);
    
%             str_area = string(area_mean) + "±" + string(area_std);
%             str_area_um = string(area_mean_um) + "±" + string(area_std_um);
%             str_circularity = string(circularity_mean) + "±" + string(circularity_std);
%             str_perimeter = string(perimeter_mean) + "±" + string(perimeter_std);
%             str_FD = string(F10_mean) + "±" + string(F10_std);
%             str_var_cen = string(var_cen_mean) + "±" + string(var_cen_std);
%             str_maxmin_cen = string(maxmin_cen_mean) + "±" + string(maxmin_cen_std);

            t = table(index, area_p, area_um, circularity, perimeters, FD, var_cen, maxmin_cen);
            % nr = {'Average', str_area, str_area_um, str_circularity, str_perimeter, str_FD,str_var_cen,str_maxmin_cen};
            %app.UITable.Data = [t; nr];
            app.UITable.Data = t;

            app.SizeandshapecompletedLamp.Color = 'green';


        end

        % Button pushed function: VisualisecarbonatecontentButton
        function VisualisecarbonatecontentButtonPushed(app, event)
            % fit the Lorentzian function to the 960 and 1070 peak and
            % extract spectral features from the Lorentzian fit. 
            [app.Lorentzmatr960, app.Lorentzcell960, app.Result960, app.meancorr960, app.Lorentzfit960, app.Lorentzpara960, app.FWHM960, app.FWHM960_hand, app.Intensity960, app.PeakPosition960, app.Lorentzbaseline960, app.integral960, app.resnorm960, app.residual960] = featureslorenzfit(app.FilterData, app.BinairyMatrix960, 930, 990);
            [app.Lorentzmatr1070, app.Lorentzcell1070, app.Result1070, app.meancorr1070, app.Lorentzfit1070, app.Lorentzpara1070, app.FWHM1070, app.FWHM1070_hand, app.Intensity1070, app.PeakPosition1070, app.Lorentzbaseline1070, app.integral1070, app.resnorm1070, app.residual1070] = featureslorenzfit(app.FilterData, app.BinairyMatrix960, 1055, 1085);
            
            % correct and normalize 960 features
            app.cor_FWHM960 = app.FWHM960;
            app.cor_FWHM960(app.FWHM960 >= 40) = NaN;
            app.FWHM960_hand(app.FWHM960_hand >= 40) = NaN;
            app.nor_integral960 = app.integral960./ app.totalareaspectra;
            app.nor_Intensity960 = app.Intensity960 ./ app.totalareaspectra;

            % create binary map for 1070 peak as quality control for Lorentzian fit.
            % Pixels should have a positive area and meet the (automatic) 
            % rsquared threshold in order to be included in binary map.
            app.BinairyMatrix1070 = app.BinairyMatrix960;
            app.BinairyMatrix1070(app.integral1070 < 0) = 0;

            % user can choose if rsquerd threshold is set automatically or
            % manual
            app.Rsquared = rsqaured(app.resnorm1070, app.meancorr1070, app.BinairyMatrix960);
            if strcmp(app.ThresholdRsquaredSwitch.Value,'Manual')
                app.BinairyMatrix1070(app.Rsquared < app.ThresholdRsquaredEditField.Value) = 0;
            else
                averagestemp1 = app.Rsquared;
                averages1 = averagestemp1./max(max(averagestemp1));
                threshold1 = graythresh(averages1);
                binaryMask1 = averages1 >= threshold1;
                app.BinairyMatrix1070(~binaryMask1) = 0;
                app.EditField_2.Value = threshold1;
            end
            
            % quality control of 1070 peak area by using 1070 binary map
            app.cor_integral1070 = app.integral1070;
            app.cor_integral1070(app.BinairyMatrix1070==0) = 0;

            % quality control of 1070 peak intensity by using 1070 binary map
            app.cor_Intensity1070 = app.Intensity1070;
            app.cor_Intensity1070(app.BinairyMatrix1070==0) = 0;
            
            % improve binary map for 1070 peak as quality control for Lorentzian fit.
            % Pixels should also meet the automatic area threshold in order to be included in binary map.
            averagestemp2 = app.cor_integral1070;
            averages2 = averagestemp2./max(max(averagestemp2));
            threshold2 = graythresh(averages2);
            binaryMask2 = averages2 >= threshold2;
            app.BinairyMatrix1070(~binaryMask2) = 0;

            % quality control of 1070 peak intensity and area by using 1070
            % binary map. Pixels which are not in 1070 binary map, but are
            % in 960 binary map get value NaN to make them appear white in
            % the mapping.
            app.cor_integral1070(app.BinairyMatrix1070==0) = NaN; % threshold quality control
            app.cor_integral1070(app.BinairyMatrix960==0) = 0;
            app.nor_cor_integral1070 = app.cor_integral1070 ./ app.totalareaspectra; 
            app.cor_Intensity1070(app.BinairyMatrix1070==0) = NaN;
            app.cor_Intensity1070(app.BinairyMatrix960==0) = 0;
            app.nor_cor_Intensity1070 = app.cor_Intensity1070 ./ app.totalareaspectra; 

            % calculate 1070:960 ratio
            app.ratioarea = app.cor_integral1070 ./ app.integral960;
            app.ratioarea(app.BinairyMatrix1070==0) = NaN;
            app.ratioarea(app.BinairyMatrix960 == 0) = 0;
            app.ratiointensity = app.cor_Intensity1070 ./ app.Intensity960;
            app.ratiointensity(app.BinairyMatrix1070==0) = NaN;
            app.ratiointensity(app.BinairyMatrix960 == 0) = 0;
            
            % plot binary matrix (960 peak) in tab 'Carbonate content'
            axis(app.UIAxes5, 'equal');
            xlim(app.UIAxes5, [0 56]);
            ylim(app.UIAxes5, [0 56]);
            imagesc(app.UIAxes5,app.x_centers, app.y_centers,app.BinairyMatrix960,"HitTest","off");
            title(app.UIAxes5, 'Binary map (960 peak)')
            
        end

        % Button pushed function: SwitchvisualisationButton_3
        function SwitchvisualisationButton_3Pushed(app, event)
            cla(app.UIAxes5)
            xlim(app.UIAxes5, [0 56]);
            ylim(app.UIAxes5, [0 56]);
            colorbar(app.UIAxes5);
            colormap(app.UIAxes5, "turbo");

            % create binary map for 1070 peak as quality control for Lorentzian fit.
            % Pixels should have a positive area and meet the (automatic) 
            % rsquared threshold in order to be included in binary map.
            app.BinairyMatrix1070 = app.BinairyMatrix960;
            app.BinairyMatrix1070(app.integral1070 < 0) = 0;

            % user can choose if rsquerd threshold is set automatically or
            % manual
            app.Rsquared = rsqaured(app.resnorm1070, app.meancorr1070, app.BinairyMatrix960);
            if strcmp(app.ThresholdRsquaredSwitch.Value,'Manual')
                app.BinairyMatrix1070(app.Rsquared < app.ThresholdRsquaredEditField.Value) = 0;
            else
                averagestemp1 = app.Rsquared;
                averages1 = averagestemp1./max(max(averagestemp1));
                threshold1 = graythresh(averages1);
                binaryMask1 = averages1 >= threshold1;
                app.BinairyMatrix1070(~binaryMask1) = 0;
                app.EditField_2.Value = threshold1;
            end
            
            % quality control of 1070 peak area by using 1070 binary map
            app.cor_integral1070 = app.integral1070;
            app.cor_integral1070(app.BinairyMatrix1070==0) = 0;

            % quality control of 1070 peak intensity by using 1070 binary map
            app.cor_Intensity1070 = app.Intensity1070;
            app.cor_Intensity1070(app.BinairyMatrix1070==0) = 0;
            
            % improve binary map for 1070 peak as quality control for Lorentzian fit.
            % Pixels should also meet the automatic area threshold in order to be included in binary map.
            averagestemp2 = app.cor_integral1070;
            averages2 = averagestemp2./max(max(averagestemp2));
            threshold2 = graythresh(averages2);
            binaryMask2 = averages2 >= threshold2;
            app.BinairyMatrix1070(~binaryMask2) = 0;

            % quality control of 1070 peak intensity and area by using 1070
            % binary map. Pixels which are not in 1070 binary map, but are
            % in 960 binary map get value NaN to make them appear white in
            % the mapping.
            app.cor_integral1070(app.BinairyMatrix1070==0) = NaN; % threshold quality control
            app.cor_integral1070(app.BinairyMatrix960==0) = 0;
            app.nor_cor_integral1070 = app.cor_integral1070 ./ app.totalareaspectra; 
            app.cor_Intensity1070(app.BinairyMatrix1070==0) = NaN;
            app.cor_Intensity1070(app.BinairyMatrix960==0) = 0;
            app.nor_cor_Intensity1070 = app.cor_Intensity1070 ./ app.totalareaspectra; 

            % calculate 1070:960 ratio
            app.ratioarea = app.cor_integral1070 ./ app.integral960;
            app.ratioarea(app.BinairyMatrix1070==0) = NaN;
            app.ratioarea(app.BinairyMatrix960 == 0) = 0;
            app.ratiointensity = app.cor_Intensity1070 ./ app.Intensity960;
            app.ratiointensity(app.BinairyMatrix1070==0) = NaN;
            app.ratiointensity(app.BinairyMatrix960 == 0) = 0;
            
            % MAPPING: based on selected option in box 'Carbonate visualisation in
            % heatmaps', a 40x40 matrix of a Raman spectral feature is
            % mapped. White pixels demonstrate pixels with an poor 1070
            % Lorentzian fit.
            if app.BinarymapButton.Value == 1
                imagesc(app.UIAxes5,app.x_centers, app.y_centers,app.BinairyMatrix960,"HitTest","off");
                title(app.UIAxes5, 'Binary map (960 peak)');
            elseif app.FWHM960lorentzianfitButton.Value == 1
                imagesc(app.UIAxes5,app.x_centers, app.y_centers,app.cor_FWHM960,"HitTest","off");
                title(app.UIAxes5, 'FWHM of 960 peak');
            elseif app.FWHM960manuallyButton.Value == 1
                imagesc(app.UIAxes5,app.x_centers, app.y_centers,app.FWHM960_hand,"HitTest","off");
                title(app.UIAxes5, 'FWHM of 960 peak');
            elseif app.Area960peakButton.Value == 1
                imagesc(app.UIAxes5,app.x_centers, app.y_centers,app.nor_integral960,"HitTest","off");
                title(app.UIAxes5, 'Area of 960 peak');
            elseif app.Intensity960peakButton.Value == 1
                imagesc(app.UIAxes5,app.x_centers, app.y_centers,app.nor_Intensity960,"HitTest","off");
                title(app.UIAxes5, 'Intensity of 960 peak');
            elseif app.Area1070peakButton.Value == 1
                imagesc(app.UIAxes5,app.x_centers, app.y_centers,app.integral1070,"HitTest","off");
                title(app.UIAxes5, 'Area of 1070 peak');
            elseif app.Intensity1070peakButton.Value == 1
                imagesc(app.UIAxes5,app.x_centers, app.y_centers,app.Intensity1070,"HitTest","off");
                title(app.UIAxes5, 'Intensity of 1070 peak');
            elseif app.corarea1070peakButton.Value == 1
                alphaData = ~isnan(app.cor_integral1070); 
                imagesc(app.UIAxes5,app.x_centers, app.y_centers,app.nor_cor_integral1070,"HitTest","off");
                set(app.UIAxes5, 'Color', 'w');
                h = findobj(app.UIAxes5, 'Type', 'Image');
                set(h, 'AlphaData', alphaData); 
                title(app.UIAxes5, 'Area of 1070 peak');
            elseif app.corIntensity1070peakButton.Value == 1
                alphaData = ~isnan(app.cor_Intensity1070); 
                imagesc(app.UIAxes5,app.x_centers, app.y_centers,app.nor_cor_Intensity1070,"HitTest","off");
                set(app.UIAxes5, 'Color', 'w');
                h = findobj(app.UIAxes5, 'Type', 'Image');
                set(h, 'AlphaData', alphaData);
                title(app.UIAxes5, 'Intensity of 1070 peak');
            elseif app.Ratioarea1070960Button.Value == 1
                alphaData = ~isnan(app.ratioarea); 
                imagesc(app.UIAxes5,app.x_centers, app.y_centers,app.ratioarea,"HitTest","off");
                set(app.UIAxes5, 'Color', 'w');
                h = findobj(app.UIAxes5, 'Type', 'Image');
                set(h, 'AlphaData', alphaData);
                title(app.UIAxes5, '1070:960 ratio (area)');
            elseif app.Ratiointensity1070960Button.Value == 1
                alphaData = ~isnan(app.ratiointensity); 
                imagesc(app.UIAxes5,app.x_centers, app.y_centers,app.ratiointensity,"HitTest","off");
                set(app.UIAxes5, 'Color', 'w');
                h = findobj(app.UIAxes5, 'Type', 'Image');
                set(h, 'AlphaData', alphaData); 
                title(app.UIAxes5, '1070:960 ratio (intensity)');
            elseif app.normresidual1070Button.Value == 1
                imagesc(app.UIAxes5,app.x_centers, app.y_centers,app.resnorm1070,"HitTest","off");
                title(app.UIAxes5, 'Residuals of 1070 Lorentzian fit');
            elseif app.Rsquared1070Button.Value == 1
                imagesc(app.UIAxes5,app.x_centers, app.y_centers,app.Rsquared,"HitTest","off");
                title(app.UIAxes5, 'R^2 of 1070 Lorentzian fit')
            end
       

        end

        % Button down function: UIAxes5
        function UIAxes5ButtonDown(app, event)
            % enable user to click on pixel of any displayed heatmap.
            clickpoint = app.UIAxes5.CurrentPoint;
            X_um = clickpoint(1,1);
            Y_um = clickpoint(1,2);
        
            um_per_pixel = 56 / 40;
            X = floor(X_um / um_per_pixel) + 1;
            Y = floor(Y_um / um_per_pixel) + 1;
        
            X = max(1, min(40, X));
            Y = max(1, min(40, Y));
        
            % plot the 960 peak corresponding to selected pixel by the
            % user: plot the original Raman intensities of the 960 peak, 
            % the Lorentzian fit and the baseline.
            x1 = app.Lorentzmatr960(:,1);
            y1 = app.Result960{Y,X};
            
            xfit1 = app.Lorentzfit960{Y,X}(:,1);
            yfit_highres1 = app.Lorentzfit960{Y,X}(:,2);
            yfit_baseline1 = app.Lorentzbaseline960{Y,X}(:,2);
        
            cla(app.UIAxes6);
            plot(app.UIAxes6,x1,y1,'b');
            hold(app.UIAxes6, 'on');
            plot(app.UIAxes6,xfit1, yfit_highres1, 'r-');
            plot(app.UIAxes6,xfit1, yfit_baseline1, 'g');
        
            % plot the 1070 peak corresponding to selected pixel by the
            % user: plot the original Raman intensities of the 1070 peak, 
            % the Lorentzian fit and the baseline.
            x2 = app.Lorentzmatr1070(:,1);
            y2 = app.Result1070{Y,X};
        
            xfit2 = app.Lorentzfit1070{Y,X}(:,1);
            yfit_highres2 = app.Lorentzfit1070{Y,X}(:,2);
            yfit_baseline2 = app.Lorentzbaseline1070{Y,X}(:,2);
        
            cla(app.UIAxes7);
            plot(app.UIAxes7,x2,y2,'b');
            hold(app.UIAxes7, 'on');
            plot(app.UIAxes7,xfit2, yfit_highres2, 'r-');
            plot(app.UIAxes7,xfit2, yfit_baseline2, 'g');           
        end

        % Button pushed function: PropertiescalcificationButton_2
        function PropertiescalcificationButton_2Pushed(app, event)
            nor_cor_integral1070 = app.nor_cor_integral1070;
            nor_cor_Intensity1070 = app.nor_cor_Intensity1070;
            ratioarea = app.ratioarea;
            ratiointensity = app.ratiointensity;
            
            % set value of NaN pixels to zero, since a poor Lorentzian fit
            % of the 1070 peak means no presence of 1070 peak.
            nor_cor_integral1070(isnan(app.cor_integral1070)) = 0;
            nor_cor_Intensity1070(isnan(app.cor_Intensity1070)) = 0;
            ratioarea(isnan(app.ratioarea)) = 0;
            ratiointensity(isnan(app.ratiointensity)) = 0;

            B = app.Boundaries;
            n = length(B);
            FWHM = zeros(n,2);
            A1070 = zeros(n,2);
            I1070 = zeros(n,2);
            RatioA = zeros(n,2);
            RatioI = zeros(n,2);
            FWHMhand = zeros(n,2);
            A960 = zeros(n,2);
            I960 = zeros(n,2);

            % calculate mean and standard deviation for each Raman spectral
            % feature
            for k = 1:length(B)
                mask = app.Label_relabel == k;
                
                % FWHM (derived from Lorentzian function)
                FWHMmean_val = mean(app.cor_FWHM960(mask), 'omitnan');
                FWHMstd_val= std(app.cor_FWHM960(mask), 'omitnan');
                FWHM(k, :) = [FWHMmean_val, FWHMstd_val];

                % FWHM (derived manually)
                FWHMmeanhand_val = mean(app.FWHM960_hand(mask), 'omitnan');
                FWHMstdhand_val= std(app.FWHM960_hand(mask), 'omitnan');
                FWHMhand(k, :) = [FWHMmeanhand_val, FWHMstdhand_val];

                % area 960
                A960mean_val = mean(app.nor_integral960(mask), 'omitnan');
                A960std_val= std(app.nor_integral960(mask), 'omitnan');
                A960(k, :) = [A960mean_val, A960std_val];

                % intensity 1070
                I960mean_val = mean(app.nor_Intensity960(mask), 'omitnan');
                I960std_val= std(app.nor_Intensity960(mask), 'omitnan');
                I960(k, :) = [I960mean_val, I960std_val];

                % area 1070
                A1070mean_val = mean(nor_cor_integral1070(mask), 'omitnan');
                A1070std_val= std(nor_cor_integral1070(mask), 'omitnan');
                A1070(k, :) = [A1070mean_val, A1070std_val];

                % intensity 1070
                I1070mean_val = mean(nor_cor_Intensity1070(mask), 'omitnan');
                I1070std_val= std(nor_cor_Intensity1070(mask), 'omitnan');
                I1070(k, :) = [I1070mean_val, I1070std_val];

                % ratio area 1070/960
                RatioA_mean_val = mean(ratioarea(mask), 'omitnan');
                RatioA_std_val= std(ratioarea(mask), 'omitnan');
                RatioA(k, :) = [RatioA_mean_val, RatioA_std_val];

                % ratio intensity 1070/960
                RatioI_mean_val = mean(ratiointensity(mask), 'omitnan');
                RatioI_std_val= std(ratiointensity(mask), 'omitnan');
                RatioI(k, :) = [RatioI_mean_val, RatioI_std_val];

                
            end

            % display carbonate properties of each microcalcification
            % in the table. Rows represent the individual
            % microcalcifications and columns represent the properties.
            FWHM960_mean = FWHM(:,1);
            FWHM960_std = FWHM(:,2);
            FWHM960hand_mean = FWHMhand(:,1);
            FWHM960hand_std = FWHMhand(:,2);
            Area960_mean = A960(:,1);
            Area960_std = A960(:,2);
            Intensity960_mean = I960(:,1);
            Intensity960_std = I960(:,2);
            Area1070_mean = A1070(:,1);
            Area1070_std = A1070(:,2);
            Intensity1070_mean = I1070(:,1);
            Intensity1070_std = I1070(:,2);
            RatioA_carb_phos_mean = RatioA(:,1);
            RatioA_carb_phos_std = RatioA(:,2);
            RatioI_carb_phos_mean = RatioI(:,1);
            RatioI_carb_phos_std = RatioI(:,2);
            
            t_index = (1:length(B))';
            t = table(t_index,FWHM960_mean,FWHM960_std,FWHM960hand_mean,FWHM960hand_std, Area960_mean,Area960_std,Intensity960_mean,Intensity960_std,Area1070_mean,Area1070_std,Intensity1070_mean,Intensity1070_std,RatioA_carb_phos_mean,RatioA_carb_phos_std,RatioI_carb_phos_mean,RatioI_carb_phos_std);
            app.UITable2.Data = t;

            app.CarbonatecontentcompletedLamp_2.Color = 'green';

        end

        % Button pushed function: VisiualiseproteincontentButton
        function VisiualiseproteincontentButtonPushed(app, event)
            app.FilterBckMatrix = app.FilterDataMatrix;
            
            % calculate and plot average background spectrum by using all zerovalued
            % pixels in binary map (960).
            for row = 1:40
                for col = 1:40
                    if app.BinairyMatrix960(row, col) ~= 0
                        app.FilterBckMatrix{row, col} = NaN(size(app.FilterBckMatrix{row, col}));
                    end
                end
            end
            
            all_vectors = NaN(length(app.FilterBckMatrix{1,1}), 1600);
            k = 1;  
            for row = 1:40
                for col = 1:40
                    v = app.FilterBckMatrix{row,col};
                    all_vectors(:,k) = v; 
                    k = k + 1;
                end
            end

            app.FilterBckMatrix = mean(all_vectors, 2, 'omitnan'); 
            plot(app.UIAxes10,app.FilterData(:,1),app.FilterBckMatrix, 'r');

            % perform background correction for each Raman spectra within
            % the microcalcifcation (pixel must have value of 1 in binary
            % map (960)). Parfor loop is used to fasten the background
            % correction for all Raman spectra.
            FilterData1 = app.FilterData(:,1);
            BckMatrix = app.FilterBckMatrix;
            BinMatrix = app.BinairyMatrix960;
            DataMatrix = cellfun(@(x) x + 0, app.FilterDataMatrix, 'UniformOutput', false);
            
            flatDataMatrix = reshape(DataMatrix, [], 1);
            flatBinMatrix = reshape(BinMatrix, [], 1);
            Cval_flat = NaN(1600,1); 
            Bckmatrix_flat = cell(1600,1);
            
            parfor idx = 1:1600
                if flatBinMatrix(idx)
                    pixelData = flatDataMatrix{idx};
                    [Bckmatrix_flat{idx}, Cval_flat(idx)] = backgroundcorr(FilterData1, pixelData, BckMatrix);
                else
                    Bckmatrix_flat{idx} = zeros(length(FilterData1), 1);
                    Cval_flat(idx) = NaN;
                end
            end
            
            app.Cvalues = reshape(Cval_flat, 40, 40);
            app.bckcorrectedMatrix = reshape(Bckmatrix_flat, 40, 40);

            % 1004 peak: calculate lorentz fit and extract its Raman spectral features
            bckcorrectedMatrix_1600 = convertCellToRawData(app.bckcorrectedMatrix,app.FilterData(:,1));
            [app.Lorentzmatr1004, app.Lorentzcell1004, app.Result1004, app.meancorr1004, app.Lorentzfit1004, app.Lorentzpara1004, app.FWHM1004, app.FWHM1004_hand, app.Intensity1004, app.PeakPosition1004, app.Lorentzbaseline1004, app.integral1004, app.resnorm1004, app.residual1004] = featureslorenzfit(bckcorrectedMatrix_1600, app.BinairyMatrix960, 985, 1015);
            app.Rsquared1004 = rsqaured(app.resnorm1004, app.meancorr1004, app.BinairyMatrix960);

            % create binary map for 1004 peak as quality control for Lorentzian fit.
            % Pixels should have a positive area and meet the automatic 
            % rsquared threshold in order to be included in binary map.
            app.BinairyMatrix1004 = app.BinairyMatrix960;
            app.BinairyMatrix1004(app.integral1004 < 0) = 0;

            averagestemp1 = app.Rsquared1004;
            averages1 = averagestemp1./max(max(averagestemp1));
            threshold1 = graythresh(averages1);
            binaryMask1 = averages1 >= threshold1;
            app.BinairyMatrix1004(~binaryMask1) = 0;
            app.ThresEditField.Value = threshold1;

            % quality control of 1004 peak area and intensity by using 1004
            % binary map 
            app.cor_integral1004 = app.integral1004;
            app.cor_integral1004(app.BinairyMatrix1004 == 0) = NaN;
            app.nor_cor_integral1004 = app.cor_integral1004./app.totalareaspectra;
            app.nor_cor_integral1004(app.BinairyMatrix1004 == 0) = NaN;
            app.nor_cor_integral1004(app.BinairyMatrix960== 0) = 0;
            app.cor_Intensity1004 = app.Intensity1004;
            app.cor_Intensity1004(app.BinairyMatrix1004 == 0) = NaN;
            app.nor_cor_Intensity1004 = app.cor_Intensity1004./app.totalareaspectra;
            app.nor_cor_Intensity1004(app.BinairyMatrix1004 == 0) = NaN;
            app.nor_cor_Intensity1004(app.BinairyMatrix960== 0) = 0;


            % 1445 peak: calculate lorentz fit and extract its Raman spectral features
            [app.Lorentzmatr1445, app.Lorentzcell1445, app.Result1445, app.meancorr1445, app.Lorentzfit1445, app.Lorentzpara1445, app.FWHM1445, app.FWHM1445_hand, app.Intensity1445, app.PeakPosition1445, app.Lorentzbaseline1445, app.integral1445, app.resnorm1445, app.residual1445] = featureslorenzfit(bckcorrectedMatrix_1600, app.BinairyMatrix960, 1400, 1490);
            app.Rsquared1445 = rsqaured(app.resnorm1445, app.meancorr1445, app.BinairyMatrix960);
            
            % create binary map for 1445 peak as quality control for Lorentzian fit.
            % Pixels should have a positive area and meet the automatic 
            % rsquared threshold in order to be included in binary map.
            app.BinairyMatrix1445 = app.BinairyMatrix960;
            app.BinairyMatrix1445(app.integral1445 < 0) = 0;

            averagestemp2 = app.Rsquared1445;
            averages2 = averagestemp2./max(max(averagestemp2));
            threshold2 = graythresh(averages2);
            binaryMask2 = averages2 >= threshold2;
            app.BinairyMatrix1445(~binaryMask2) = 0;
            app.ThresEditField_2.Value = threshold2;

            % quality control of 1445 peak area and intensity by using 1004
            % binary map 
            app.cor_integral1445 = app.integral1445;
            app.cor_integral1445(app.BinairyMatrix1445 == 0) = NaN;
            app.nor_cor_integral1445 = app.cor_integral1445./app.totalareaspectra;
            app.nor_cor_integral1445(app.BinairyMatrix1445 == 0) = NaN;
            app.nor_cor_integral1445(app.BinairyMatrix960 == 0) = 0;
            app.cor_Intensity1445 = app.Intensity1445;
            app.cor_Intensity1445(app.BinairyMatrix1445 == 0) = NaN;
            app.nor_cor_Intensity1445 = app.cor_Intensity1445./app.totalareaspectra;
            app.nor_cor_Intensity1445(app.BinairyMatrix1445 == 0) = NaN;
            app.nor_cor_Intensity1445(app.BinairyMatrix960 == 0) = 0;

            % 1650 peak: calculate lorentz fit and extract its Raman spectral features
            [app.Lorentzmatr1650, app.Lorentzcell1650, app.Result1650, app.meancorr1650, app.Lorentzfit1650, app.Lorentzpara1650, app.FWHM1650, app.FWHM1650_hand, app.Intensity1650, app.PeakPosition1650, app.Lorentzbaseline1650, app.integral1650, app.resnorm1650, app.residual1650] = featureslorenzfit(bckcorrectedMatrix_1600, app.BinairyMatrix960, 1630, 1700);
            app.Rsquared1650 = rsqaured(app.resnorm1650, app.meancorr1650, app.BinairyMatrix960);
            
            % create binary map for 1445 peak as quality control for Lorentzian fit.
            % Pixels should have a positive area and meet the automatic 
            % rsquared threshold in order to be included in binary map.
            app.BinairyMatrix1650 = app.BinairyMatrix960;
            app.BinairyMatrix1650(app.integral1650 < 0) = 0;

            averagestemp3 = app.Rsquared1650;
            averages3 = averagestemp3./max(max(averagestemp3));
            threshold3 = graythresh(averages3);
            binaryMask3 = averages3 >= threshold3;
            app.BinairyMatrix1650(~binaryMask3) = 0;
            app.ThresEditField_3.Value = threshold3;

            % quality control of 1650 peak area and intensity by using 1004
            % binary map 
            app.cor_integral1650 = app.integral1650;
            app.cor_integral1650(app.BinairyMatrix1650 == 0) = NaN;
            app.nor_cor_integral1650 = app.cor_integral1650./app.totalareaspectra;
            app.nor_cor_integral1650(app.BinairyMatrix1650 == 0) = NaN;
            app.nor_cor_integral1650(app.BinairyMatrix960 == 0) = 0;
            app.cor_Intensity1650 = app.Intensity1650;
            app.cor_Intensity1650(app.BinairyMatrix1650 == 0) = NaN;
            app.nor_cor_Intensity1650 = app.cor_Intensity1650./app.totalareaspectra;
            app.nor_cor_Intensity1650(app.BinairyMatrix1650 == 0) = NaN;
            app.nor_cor_Intensity1650(app.BinairyMatrix960 == 0) = 0;

            % calculate 1004:960 and 1070:1004 ratios
            app.ratio_phe_hap= app.cor_Intensity1004 ./ app.Intensity960;
            app.ratio_phe_hap(app.Intensity960 == 0) = NaN;
            app.ratio_phe_hap(isnan(app.ratio_phe_hap)) = NaN;
            app.ratio_phe_hap(app.BinairyMatrix960 == 0) = 0;
            app.ratio_CO32_phe = app.cor_Intensity1070 ./ app.cor_Intensity1004;
            app.ratio_CO32_phe(app.cor_Intensity1004 == 0) = NaN;
            app.ratio_CO32_phe(isnan(app.ratio_CO32_phe)) = NaN;
            app.ratio_CO32_phe(app.BinairyMatrix960 == 0) = 0;

        end

        % Button down function: UIAxes11
        function UIAxes11ButtonDown(app, event)
             % enable user to click on pixel of any displayed heatmap.
            clickpoint = app.UIAxes11.CurrentPoint;
            X_um = clickpoint(1,1);
            Y_um = clickpoint(1,2);

            um_per_pixel = 56 / 40;
            X = floor(X_um / um_per_pixel) + 1;
            Y = floor(Y_um / um_per_pixel) + 1;
        
            X = max(1, min(40, X));
            Y = max(1, min(40, Y));
        
            % plot the background spectrum and the background corrected
            % Raman spectrum corresponding to selected pixel by the
            % user.
            x = app.FilterData(:,1);
            y = app.FilterDataMatrix{Y,X};
            y_cor = app.bckcorrectedMatrix{Y,X};
        
            cla(app.UIAxes10);
            plot(app.UIAxes10, x, app.FilterBckMatrix, 'r');
            hold(app.UIAxes10, 'on');
            plot(app.UIAxes10, x, y, 'b');
            plot(app.UIAxes10, x, y_cor, 'k');
        
            % plot the 1004 peak corresponding to selected pixel by the
            % user: plot the background corrected Raman intensities of the 1004 peak, 
            % the Lorentzian fit and the baseline.
            x2 = app.Lorentzmatr1004(:,1);
            y2 = app.Result1004{Y,X};
            xfit2 = app.Lorentzfit1004{Y,X}(:,1);
            yfit_highres2 = app.Lorentzfit1004{Y,X}(:,2);
            yfit_baseline2 = app.Lorentzbaseline1004{Y,X}(:,2);
        
            cla(app.UIAxes12);
            plot(app.UIAxes12, x2, y2, 'k');
            hold(app.UIAxes12, 'on');
            plot(app.UIAxes12, xfit2, yfit_highres2, 'r-');
            plot(app.UIAxes12, xfit2, yfit_baseline2, 'g');

            % plot the 1445 peak corresponding to selected pixel by the
            % user: plot the background corrected Raman intensities of the 1445 peak, 
            % the Lorentzian fit and the baseline.
            x3 = app.Lorentzmatr1445(:,1);
            y3 = app.Result1445{Y,X};
            xfit3 = app.Lorentzfit1445{Y,X}(:,1);
            yfit_highres3 = app.Lorentzfit1445{Y,X}(:,2);
            yfit_baseline3 = app.Lorentzbaseline1445{Y,X}(:,2);
        
            cla(app.UIAxes12_2);
            plot(app.UIAxes12_2, x3, y3, 'k');
            hold(app.UIAxes12_2, 'on');
            plot(app.UIAxes12_2, xfit3, yfit_highres3, 'r-');
            plot(app.UIAxes12_2, xfit3, yfit_baseline3, 'g');
        
            % plot the 1650 peak corresponding to selected pixel by the
            % user: plot the background corrected Raman intensities of the 1650 peak, 
            % the Lorentzian fit and the baseline.
            x4 = app.Lorentzmatr1650(:,1);
            y4 = app.Result1650{Y,X};
            xfit4 = app.Lorentzfit1650{Y,X}(:,1);
            yfit_highres4 = app.Lorentzfit1650{Y,X}(:,2);
            yfit_baseline4 = app.Lorentzbaseline1650{Y,X}(:,2);
        
            cla(app.UIAxes12_3);
            plot(app.UIAxes12_3, x4, y4, 'k');
            hold(app.UIAxes12_3, 'on');
            plot(app.UIAxes12_3, xfit4, yfit_highres4, 'r-');
            plot(app.UIAxes12_3, xfit4, yfit_baseline4, 'g');
        end

        % Button pushed function: SwitchvisualisationButton_2
        function SwitchvisualisationButton_2Pushed(app, event)
            axis(app.UIAxes11, 'equal');
            xlim(app.UIAxes11, [0 56]);
            ylim(app.UIAxes11, [0 56]);
            
            % MAPPING: based on selected option in box 'Protein visualisation in
            % heatmaps', a 40x40 matrix of a Raman spectral feature is
            % mapped. White pixels demonstrate pixels with an poor 1004,
            % 1445 or 1650 Lorentzian fit.
            if app.area1004Button.Value == 1
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.integral1004,"HitTest","off");
                title(app.UIAxes11, 'Area of 1004 peak')
            elseif app.intensity1004Button.Value == 1
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.Intensity1004, "HitTest","off");
                title(app.UIAxes11, 'Intensity of 1004 peak')
            elseif app.resnorm1004Button.Value == 1
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.resnorm1004,"HitTest","off");
                title(app.UIAxes11, 'Residuals of 1004 Lorentzian fit')
            elseif app.rsquared1004Button.Value == 1
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.Rsquared1004, "HitTest","off")
                title(app.UIAxes11, 'R^2 of 1004 Lorentzian fit')
            elseif app.binary1004Button.Value == 1
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.BinairyMatrix1004, "HitTest","off")
                title(app.UIAxes11, 'Binary map (1004 peak)')
            elseif app.corarea1004Button.Value == 1
                alphaData = ~isnan(app.nor_cor_integral1004); 
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.nor_cor_integral1004,"HitTest","off");
                set(app.UIAxes11, 'Color', 'w');
                h = findobj(app.UIAxes11, 'Type', 'Image');
                set(h, 'AlphaData', alphaData); 
                title(app.UIAxes11, 'Area of 1004 peak')
            elseif app.corintensity1004Button.Value == 1
                alphaData = ~isnan(app.nor_cor_Intensity1004); 
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.nor_cor_Intensity1004,"HitTest","off");
                set(app.UIAxes11, 'Color', 'w');
                h = findobj(app.UIAxes11, 'Type', 'Image');
                set(h, 'AlphaData', alphaData); 
                title(app.UIAxes11, 'Intensity of 1004 peak')
            elseif app.area1445Button.Value == 1
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.integral1445,"HitTest","off");
                title(app.UIAxes11, 'Area of 1445 peak')
            elseif app.intensity1445Button.Value == 1
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.Intensity1445, "HitTest","off");
                title(app.UIAxes11, 'Intensity of 1445 peak')
            elseif app.resnorm1445Button.Value == 1
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.resnorm1445,"HitTest","off");
                title(app.UIAxes11, 'Residuals of 1445 Lorentzian fit')
            elseif app.rsquared1445Button.Value == 1
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.Rsquared1445, "HitTest","off");
                title(app.UIAxes11, 'R^2 of 1445 Lorentzian fit')
            elseif app.binary1445Button.Value == 1
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.BinairyMatrix1445, "HitTest","off");
                title(app.UIAxes11, 'Binary map (1445 peak)')
            elseif app.corarea1445Button.Value == 1
                alphaData = ~isnan(app.nor_cor_integral1445); 
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.nor_cor_integral1445,"HitTest","off");
                set(app.UIAxes11, 'Color', 'w');
                h = findobj(app.UIAxes11, 'Type', 'Image');
                set(h, 'AlphaData', alphaData); 
                title(app.UIAxes11, 'Area of 1445 peak')
            elseif app.corintensity1445Button.Value == 1
                alphaData = ~isnan(app.nor_cor_Intensity1445); 
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.nor_cor_Intensity1445,"HitTest","off");
                set(app.UIAxes11, 'Color', 'w');
                h = findobj(app.UIAxes11, 'Type', 'Image');
                set(h, 'AlphaData', alphaData); 
                title(app.UIAxes11, 'Intensity of 1445 peak')
            elseif app.area1650Button.Value == 1
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.integral1650,"HitTest","off");
                title(app.UIAxes11, 'Area of 1650 peak')
            elseif app.intensity1650Button.Value == 1
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.Intensity1650, "HitTest","off");
                title(app.UIAxes11, 'Intensity of 1650 peak')
            elseif app.resnorm1650Button.Value == 1
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.resnorm1650,"HitTest","off");
                title(app.UIAxes11, 'Residuals of 1650 Lorentzian fit')
            elseif app.rsquared1650Button.Value == 1
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.Rsquared1650, "HitTest","off");
                title(app.UIAxes11, 'R^2 of 1650 Lorentzian fit')
            elseif app.binary1650Button.Value == 1
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.BinairyMatrix1650, "HitTest","off");
                title(app.UIAxes11, 'Binary map (1650 peak)')
            elseif app.corarea1650Button.Value == 1
                alphaData = ~isnan(app.nor_cor_integral1650); 
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.nor_cor_integral1650,"HitTest","off");
                set(app.UIAxes11, 'Color', 'w');
                h = findobj(app.UIAxes11, 'Type', 'Image');
                set(h, 'AlphaData', alphaData); 
                title(app.UIAxes11, 'Area of 1650 peak')
            elseif app.corintensity1650Button.Value == 1
                alphaData = ~isnan(app.nor_cor_Intensity1650); 
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.nor_cor_Intensity1650,"HitTest","off");
                set(app.UIAxes11, 'Color', 'w');
                h = findobj(app.UIAxes11, 'Type', 'Image');
                set(h, 'AlphaData', alphaData); 
                title(app.UIAxes11, 'Intensity of 1650 peak')
            elseif app.ratiophe1HapButton.Value == 1
                alphaData = ~isnan(app.ratio_phe_hap); 
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.ratio_phe_hap,"HitTest","off");
                set(app.UIAxes11, 'Color', 'w');
                h = findobj(app.UIAxes11, 'Type', 'Image');
                set(h, 'AlphaData', alphaData); 
                title(app.UIAxes11, 'Ratio 1004:960 (intensity)')
            elseif app.ratioCO32phe1Button.Value == 1
                alphaData = ~isnan(app.ratio_CO32_phe); 
                imagesc(app.UIAxes11,app.x_centers, app.y_centers,app.ratio_CO32_phe,"HitTest","off");
                set(app.UIAxes11, 'Color', 'w');
                h = findobj(app.UIAxes11, 'Type', 'Image');
                set(h, 'AlphaData', alphaData);
                title(app.UIAxes11, 'Ratio 1070:1040 (intensity)')
            end

            % set colormap of the heatmaps
            if app.binary1650Button.Value == 1 || app.binary1445Button.Value == 1 || app.binary1004Button.Value == 1
                colormap(app.UIAxes11, "gray");
                colorbar(app.UIAxes11);
            else
                colormap(app.UIAxes11, "turbo");
                colorbar(app.UIAxes11);
            end


        end

        % Button pushed function: PropertiescalcificationButton_4
        function PropertiescalcificationButton_4Pushed(app, event)
            B = app.Boundaries;
            L_relabel = app.Label_relabel;
            stats = app.stats_relabel;

            nor_cor_integral1004 = app.nor_cor_integral1004;
            nor_cor_Intensity1004 = app.nor_cor_Intensity1004;
            nor_cor_integral1445 = app.nor_cor_integral1445;
            nor_cor_Intensity1445 = app.nor_cor_Intensity1445;
            nor_cor_integral1650 = app.nor_cor_integral1650;
            nor_cor_Intensity1650 = app.nor_cor_Intensity1650;
            ratio_phe_hap = app.ratio_phe_hap;
            ratio_CO32_phe = app.ratio_CO32_phe;
            
            % set value of NaN pixels to zero, since a poor Lorentzian fit
            % of the 1004/1445/1650 peaks means no presence of a peak.
            nor_cor_integral1004(isnan(app.nor_cor_integral1004)) = 0;
            nor_cor_Intensity1004(isnan(app.nor_cor_Intensity1004)) = 0;
            nor_cor_integral1445(isnan(app.nor_cor_integral1445)) = 0;
            nor_cor_Intensity1445(isnan(app.nor_cor_Intensity1445)) = 0;
            nor_cor_integral1650(isnan(app.nor_cor_integral1650)) = 0;
            nor_cor_Intensity1650(isnan(app.nor_cor_Intensity1650)) = 0;
            ratio_phe_hap(isnan(app.ratio_phe_hap)) = 0;
            ratio_CO32_phe(isnan(app.ratio_CO32_phe)) = 0;

            n = length(B);
            A1004 = zeros(n,2);
            I1004 = zeros(n,2);
            A1445 = zeros(n,2);
            I1445 = zeros(n,2);
            A1650 = zeros(n,2);
            I1650 = zeros(n,2);
            phe_hap = zeros(n,2);
            CO32_phe = zeros(n,2);

            % calculate mean and standard deviation for each Raman spectral
            % feature
            for k = 1:length(B)
                mask = app.Label_relabel == k;

                % area 1004
                A1004mean_val = mean(nor_cor_integral1004(mask), 'omitnan');
                A1004std_val= std(nor_cor_integral1004(mask), 'omitnan');
                A1004(k, :) = [A1004mean_val, A1004std_val];

                % intensity 1004
                I1004mean_val = mean(nor_cor_Intensity1004(mask), 'omitnan');
                I1004std_val= std(nor_cor_Intensity1004(mask), 'omitnan');
                I1004(k, :) = [I1004mean_val, I1004std_val];

                % area 1445
                A1445mean_val = mean(nor_cor_integral1445(mask), 'omitnan');
                A1445std_val= std(nor_cor_integral1445(mask), 'omitnan');
                A1445(k, :) = [A1445mean_val, A1445std_val];

                % intensity 1445
                I1445mean_val = mean(nor_cor_Intensity1445(mask), 'omitnan');
                I1445std_val= std(nor_cor_Intensity1445(mask), 'omitnan');
                I1445(k, :) = [I1445mean_val, I1445std_val];
                
                % area 1650
                A1650mean_val = mean(nor_cor_integral1650(mask), 'omitnan');
                A1650std_val= std(nor_cor_integral1650(mask), 'omitnan');
                A1650(k, :) = [A1650mean_val, A1650std_val];

                % intensity 1650
                I1650mean_val = mean(nor_cor_Intensity1650(mask), 'omitnan');
                I1650std_val= std(nor_cor_Intensity1650(mask), 'omitnan');
                I1650(k, :) = [I1650mean_val, I1650std_val];

                % ratio phe1/hap
                phe_hapmean_val = mean(ratio_phe_hap(mask), 'omitnan');
                phe_hapstd_val= std(ratio_phe_hap(mask), 'omitnan');
                phe_hap(k, :) = [phe_hapmean_val, phe_hapstd_val];

                % ratio CO32/phe1
                CO32_phemean_val = mean(ratio_CO32_phe(mask), 'omitnan');
                CO32_phestd_val= std(ratio_CO32_phe(mask), 'omitnan');
                CO32_phe(k, :) = [CO32_phemean_val, CO32_phestd_val];
                
            end

            % display protein content properties of each microcalcification
            % in the table. Rows represent the individual
            % microcalcifications and columns represent the properties.
            Area1004_mean = A1004(:,1);
            Area1004_std =A1004(:,2);
            Intensity1004_mean = I1004(:,1);
            Intensity1004_std = I1004(:,2);
            Area1445_mean = A1445(:,1);
            Area1445_std = A1445(:,2);
            Intensity1445_mean = I1445(:,1);
            Intensity1445_std = I1445(:,2);
            Area1650_mean = A1650(:,1);
            Area1650_std = A1650(:,2);
            Intensity1650_mean = I1650(:,1);
            Intensity1650_std = I1650(:,2);
            RatioI_phe_hap_mean = phe_hap(:,1);
            RatioI_phe_hap_std = phe_hap(:,2);
            RatioI_CO32_phe_mean = CO32_phe(:,1);
            RatioI_CO32_phe_std = CO32_phe(:,2);


            t_index = (1:length(B))';
            t = table(t_index,Area1004_mean,Area1004_std,Intensity1004_mean,Intensity1004_std,Area1445_mean,Area1445_std, Intensity1445_mean, Intensity1445_std,Area1650_mean,Area1650_std, Intensity1650_mean, Intensity1650_std, RatioI_phe_hap_mean, RatioI_phe_hap_std, RatioI_CO32_phe_mean, RatioI_CO32_phe_std);
            app.UITable4.Data = t;  

            app.ProteincontentcompletedLamp.Color = 'green';
        end

        % Button pushed function: 
        % VisualisecrystallinitymagnesiumcontentButton
        function VisualisecrystallinitymagnesiumcontentButtonPushed(app, event)
            % calculate the FWHM960, using the parameters of the Lorentzian
            % fit
            app.InvFWHM960 = ones(40);
            app.InvFWHM960 = app.InvFWHM960./app.FWHM960;
            app.InvFWHM960(app.FWHM960 >= 60) = NaN;
            app.InvFWHM960(app.FWHM960 == 0) = 0;

            % calculate the FWHM960, using the manual method
            app.InvFWHM960_hand = ones(40);
            app.InvFWHM960_hand = app.InvFWHM960_hand./app.FWHM960_hand;
            app.InvFWHM960_hand(app.FWHM960_hand == 0) = 0;

            % calculate the peakposition 960 + shift 960
            app.shift960 = app.PeakPosition960 - app.WvnPhosphateEditField.Value;
            app.shift960(app.PeakPosition960 == 0) = 0;

            % calculate the peakposition 1070
            app.cor_PeakPosition1070 = app.PeakPosition1070;
            app.cor_PeakPosition1070(app.BinairyMatrix1070 == 0) = NaN;
            app.cor_PeakPosition1070(app.BinairyMatrix960 == 0) = 0;

            % calculate the shift 1070
            app.shift1070 = app.PeakPosition1070 - app.WvnCarbonateEditField.Value;
            app.shift1070(app.PeakPosition1070 == 0) = 0;
            app.cor_shift1070 = app.shift1070;
            app.cor_shift1070(app.BinairyMatrix1070 == 0) = NaN;
            app.cor_shift1070(app.BinairyMatrix960 == 0) = 0;
            
            % plot the binary map in tab 'Crystalllinity + magnesium content'
            axis(app.UIAxes8, 'equal');
            xlim(app.UIAxes8, [0 56]);
            ylim(app.UIAxes8, [0 56]);
            imagesc(app.UIAxes8, app.x_centers, app.y_centers, app.BinairyMatrix960,"HitTest","off");
        end

        % Button pushed function: SwitchvisualisationButton
        function SwitchvisualisationButtonPushed(app, event)
        cla(app.UIAxes8);
        xlim(app.UIAxes8, [0 56]);
        ylim(app.UIAxes8, [0 56]); 
        
        % MAPPING: based on selected option in box 'Crystallinity and magnesium 
        % visualisation in heatmaps', a 40x40 matrix of a Raman spectral feature is
        % mapped. White pixels demonstrate pixels with an poor 1070
        % Lorentzian fit.
        if app.InverseFWHM960LorentzianfitButton.Value == 1
            imagesc(app.UIAxes8, app.x_centers, app.y_centers,app.InvFWHM960, 'HitTest', 'off');
            clim(app.UIAxes8, 'auto');
            colormap(app.UIAxes8, "turbo");
            colorbar(app.UIAxes8);
            title(app.UIAxes8, 'Inverse FWHM of 960 peak')

        elseif app.InverseFWHM960manuallyButton.Value == 1
            imagesc(app.UIAxes8, app.x_centers, app.y_centers,app.InvFWHM960_hand, 'HitTest', 'off');
            clim(app.UIAxes8, 'auto');
            colormap(app.UIAxes8, "turbo");
            colorbar(app.UIAxes8);
            title(app.UIAxes8, 'Inverse FWHM of 960 peak')
        
        elseif app.XcoordinatephosphateButton.Value == 1
            imagesc(app.UIAxes8, app.x_centers, app.y_centers,app.PeakPosition960, 'HitTest', 'off');
            clim(app.UIAxes8, 'auto');
            colormap(app.UIAxes8, "turbo");
            colorbar(app.UIAxes8);
            title(app.UIAxes8, 'Peak position of 960 peak')
        
        elseif app.ShiftphosphatepeakButton.Value == 1
            % set a colorbar to represent blue and red shift of phosphate
            % peak
            data = app.shift960;
            max_val = max(abs(data(:)));
            n = 500;
            sharpness = 0.001;
            x = linspace(0, 1, n)'.^0.5;
            neg = [flipud(x), zeros(n,1), zeros(n,1)];  % full red to black
            pos = [zeros(n,1), zeros(n,1), x];          % black to full blue
            mid = repmat([0 0 0], round(n * sharpness), 1);  % black center
            custom_cmap = [neg; mid; pos];
            imagesc(app.UIAxes8,app.x_centers, app.y_centers,data);
            clim(app.UIAxes8, [-max_val, max_val]);
            colormap(app.UIAxes8, custom_cmap);
            colorbar(app.UIAxes8);
            set(app.UIAxes8.Children, 'HitTest', 'off');
            title(app.UIAxes8, 'Shift of 960 peak')
        
        elseif app.XcoordinatecarbonateButton.Value == 1
            imagesc(app.UIAxes8, app.x_centers, app.y_centers,app.PeakPosition1070, 'HitTest', 'off');
            clim(app.UIAxes8, 'auto');
            colormap(app.UIAxes8, "turbo");
            colorbar(app.UIAxes8);
            title(app.UIAxes8, 'Peak position of 1070 peak')
        
        elseif app.ShiftcarbonatepeakButton.Value == 1
            imagesc(app.UIAxes8, app.x_centers, app.y_centers,app.shift1070, 'HitTest', 'off');
            clim(app.UIAxes8, 'auto');
            colormap(app.UIAxes8, "turbo");
            colorbar(app.UIAxes8);
            title(app.UIAxes8, 'Shift of 1070 peak')
        
        elseif app.corrXcoordinatecarbonateButton.Value == 1
            alphaData = ~isnan(app.cor_PeakPosition1070);
            imagesc(app.UIAxes8,app.x_centers, app.y_centers, app.cor_PeakPosition1070, 'HitTest', 'off');
            set(app.UIAxes8, 'Color', 'w');
            h = findobj(app.UIAxes8, 'Type', 'Image');
            set(h, 'AlphaData', alphaData);
            clim(app.UIAxes8, 'auto');
            colormap(app.UIAxes8, "turbo");
            colorbar(app.UIAxes8);
            title(app.UIAxes8, 'Peak position of 1070 peak')
        
        elseif app.corrShiftcarbonatepeakButton.Value == 1
            % set a colorbar to represent blue and red shift of carbonate
            % peak
            data = app.cor_shift1070;
            alphaData = ~isnan(data);
            set(app.UIAxes8, 'Color', 'w');
            n = 500;
            sharpness = 0.001;
            x = linspace(0, 1, n)'.^0.5;
            neg = [flipud(x), zeros(n,1), zeros(n,1)];  % full red to black
            pos = [zeros(n,1), zeros(n,1), x];          % black to full blue
            mid = repmat([0 0 0], round(n * sharpness), 1);  % black center
            custom_cmap = [neg; mid; pos];
            imagesc(app.UIAxes8,app.x_centers, app.y_centers,data);
            h = findobj(app.UIAxes8, 'Type', 'Image');
            set(h, 'AlphaData', alphaData);
            max_val = max(abs(data(:)));
            clim(app.UIAxes8, [-max_val, max_val]);
            colormap(app.UIAxes8, custom_cmap);
            colorbar(app.UIAxes8);
            set(app.UIAxes8.Children, 'HitTest', 'off');
            title(app.UIAxes8, 'Shift of 1070 peak')
        end

        end

        % Button down function: UIAxes8
        function UIAxes8ButtonDown(app, event)
            %
            clickpoint = app.UIAxes8.CurrentPoint;
            X_um = clickpoint(1,1);
            Y_um = clickpoint(1,2);
        
            um_per_pixel = 56 / 40;
            X = floor(X_um / um_per_pixel) + 1;
            Y = floor(Y_um / um_per_pixel) + 1;
        
            X = max(1, min(40, X));
            Y = max(1, min(40, Y));

            % plot the 960 peak corresponding to selected pixel by the
            % user: plot the original Raman intensities of the 960 peak, 
            % the Lorentzian fit and the baseline.
            x1 = app.Lorentzmatr960(:,1);
            y1 = app.Result960{Y,X};
        
            xfit1 = app.Lorentzfit960{Y,X}(:,1);
            yfit_highres1 = app.Lorentzfit960{Y,X}(:,2);
            yfit_baseline1 = app.Lorentzbaseline960{Y,X}(:,2);
        
            cla(app.UIAxes9);
            plot(app.UIAxes9, x1, y1, 'b');
            hold(app.UIAxes9, 'on');
            plot(app.UIAxes9, xfit1, yfit_highres1, 'r-');
            plot(app.UIAxes9, xfit1, yfit_baseline1, 'g');
        
            % plot the 1070 peak corresponding to selected pixel by the
            % user: plot the original Raman intensities of the 1070 peak, 
            % the Lorentzian fit and the baseline.
            x2 = app.Lorentzmatr1070(:,1);
            y2 = app.Result1070{Y,X};
        
            xfit2 = app.Lorentzfit1070{Y,X}(:,1);
            yfit_highres2 = app.Lorentzfit1070{Y,X}(:,2);
            yfit_baseline2 = app.Lorentzbaseline1070{Y,X}(:,2);
        
            cla(app.UIAxes9_2);
            plot(app.UIAxes9_2, x2, y2, 'b');
            hold(app.UIAxes9_2, 'on');
            plot(app.UIAxes9_2, xfit2, yfit_highres2, 'r-');
            plot(app.UIAxes9_2, xfit2, yfit_baseline2, 'g');

        end

        % Button pushed function: PropertiescalcificationButton_3
        function PropertiescalcificationButton_3Pushed(app, event)
            B = app.Boundaries;
            L_relabel = app.Label_relabel;
            stats = app.stats_relabel;

            % calculate mean and standard deviation for each Raman spectral
            % feature
            for k = 1:length(B)
                mask = app.Label_relabel == k;
                
                %Inverse FWHM, using parameters of Lorentzian fit
                InvFWHMmean_val = mean(app.InvFWHM960(mask), 'omitnan');
                InvFWHMstd_val= std(app.InvFWHM960(mask), 'omitnan');
                InvFWHM(k, :) = [InvFWHMmean_val, InvFWHMstd_val];

                %Inverse FWHM, using manual method
                InvFWHMmeanhand_val = mean(app.InvFWHM960_hand(mask), 'omitnan');
                InvFWHMstdhand_val= std(app.InvFWHM960_hand(mask), 'omitnan');
                InvFWHMhand(k, :) = [InvFWHMmeanhand_val, InvFWHMstdhand_val];

                % X coordinate position 960
                X960mean_val = mean(app.PeakPosition960(mask), 'omitnan');
                X960std_val= std(app.PeakPosition960(mask), 'omitnan');
                X960(k, :) = [X960mean_val, X960std_val];

                % shift 960
                S960mean_val = mean(app.shift960(mask), 'omitnan');
                S960std_val= std(app.shift960(mask), 'omitnan');
                S960(k, :) = [S960mean_val, S960std_val];

                % X coordinate position 1070
                X1070mean_val = mean(app.cor_PeakPosition1070(mask), 'omitnan');
                X1070std_val= std(app.cor_PeakPosition1070(mask), 'omitnan');
                X1070(k, :) = [X1070mean_val, X1070std_val];

                % shift 1070
                S1070mean_val = mean(app.cor_shift1070(mask), 'omitnan');
                S1070std_val= std(app.cor_shift1070(mask), 'omitnan');
                S1070(k, :) = [S1070mean_val, S1070std_val];
            end

            % display crystallinity+magnesium properties of each microcalcification
            % in the table. Rows represent the individual
            % microcalcifications and columns represent the properties.
            InverseFWHM960_mean = InvFWHM(:,1);
            InverseFWHM960_std = InvFWHM(:,2);
            InverseFWHM960hand_mean = InvFWHMhand(:,1);
            InverseFWHM960hand_std = InvFWHMhand(:,2);
            Peakposition960_mean = X960(:,1);
            Peakposition960_std = X960(:,2);
            Shift960_mean = S960(:,1);
            Shift960_std = S960(:,2);
            Peakposition1070_mean = X1070(:,1);
            Peakposition1070_std = X1070(:,2);
            Shift1070_mean = S1070(:,1);
            Shift1070_std = S1070(:,2);

            t_index = (1:length(B))';
            t = table(t_index,InverseFWHM960_mean,InverseFWHM960_std,InverseFWHM960hand_mean,InverseFWHM960hand_std,Peakposition960_mean,Peakposition960_std,Shift960_mean, ...
                Shift960_std,Peakposition1070_mean,Peakposition1070_std,Shift1070_mean,Shift1070_std);
            app.UITable3.Data = t;

            app.CrystallintymagnsiumcontentcompletedLamp.Color = 'green';
        end

        % Button pushed function: ExportpropertiesmanuallyButton
        function ExportpropertiesmanuallyButtonPushed(app, event)
            % export an excelsheet manually with the rows representing the
            % microcalcifications found in the raster scan and with the
            % columns representing the different parameters.
            SheetName = app.FileName;
            SheetName = regexprep(SheetName, '[:\\/\?\*\[\]]', '_');
            SheetName = SheetName(1:min(22, end));
            [filename, folder] = uiputfile("results_patient_#.xlsx");
            complete_data = [app.UITable.Data app.UITable2.Data(:,2:end) app.UITable4.Data(:,2:end) app.UITable3.Data(:,2:end)];
            writetable(complete_data,fullfile(folder,filename),'sheet',SheetName)  
        end

        % Button pushed function: ExportpropertiesofsinglefileButton
        function ExportpropertiesofsinglefileButtonPushed(app, event)
            % export the microcalcifications and the corresponding parameters
            % present in a single file. The user only has to upload the
            % file of the raster scan area and the automatic pipeline will
            % give excellsheet as result.
            
            %upload + filter
            UploadRamanDataButtonPushed(app);
            app.BaselineCorrectionCheckBox.Value = 1;
            app.TruncationofSpectrumCheckBox.Value = 1;
            app.NormalizationbeforesubstractingpropertiesCheckBox.Value = 0;
            app.NormalizationaftersubstractingpropertiesCheckBox.Value=1;
            PreprocessingButtonPushed(app);

            % size + shape
            PlotIntensitymap960peakButtonPushed(app);
            app.ThresholdSwitch.Value = 'Automatic';
            PlotBinarymapButtonPushed(app);
            app.PixelconnectivitySwitch.Value = '4';
            PropertiescalcificationButtonPushed(app);

            %carbonate content
            app.ThresholdRsquaredSwitch.Value = 'Automatic';
            VisualisecarbonatecontentButtonPushed(app);
            PropertiescalcificationButton_2Pushed(app);

            %protein content
            VisiualiseproteincontentButtonPushed(app);
            PropertiescalcificationButton_4Pushed(app);

            %crystallinity + Magnesium
            VisualisecrystallinitymagnesiumcontentButtonPushed(app);
            PropertiescalcificationButton_3Pushed(app);

            % create excelsheet with rows representing microcalcifications
            % and columns representing parameters.
            SheetName = app.FileName;
            SheetName = regexprep(SheetName, '[:\\/\?\*\[\]]', '_');
            SheetName = SheetName(1:min(22, end));
            [filename, folder] = uiputfile("results_patient_#.xlsx");
            complete_data = [app.UITable.Data app.UITable2.Data(:,2:end) app.UITable4.Data(:,2:end) app.UITable3.Data(:,2:end)];
            writetable(complete_data,fullfile(folder,filename),'sheet',SheetName)

        end

        % Button pushed function: ExportpropertiesofpatientfolderButton
        function ExportpropertiesofpatientfolderButtonPushed(app, event)
            % export the microcalcifications and the corresponding parameters
            % present in all the files of the selected folder. 
            % The user only has to upload the folder and the automatic pipeline will
            % give an excellsheet as result with all the
            % microcalcifications within all the rasterscans in the folder.
            % 
            
            folderPath = uigetdir;
            folderPath = string(folderPath);

            Files = [];
            extensions = {'*.asc', '*.txt', '*.bin'};
            for k = 1:numel({'*.asc', '*.txt', '*.bin'})
                Files = [Files; dir(fullfile(folderPath, extensions{k}))];
            end

            m = 1;
            Results_allfiles = [];
            for i = 1:length(Files)
                currentFile = fullfile(Files(i).folder, Files(i).name);
                app.FileName = Files(i).name;

                app.CurrentFilePath = currentFile;
                UploadRamanDataButtonPushed(app, []);

                %upload + filter
                app.BaselineCorrectionCheckBox.Value = 1;
                app.TruncationofSpectrumCheckBox.Value = 1;
                app.NormalizationbeforesubstractingpropertiesCheckBox.Value = 0;
                app.NormalizationaftersubstractingpropertiesCheckBox.Value=1;
                PreprocessingButtonPushed(app);
  
                % size + shape
                PlotIntensitymap960peakButtonPushed(app);
                app.ThresholdSwitch.Value = 'Automatic';
                PlotBinarymapButtonPushed(app);
                PlotsegmentedbinarymapButtonPushed(app);
                app.PixelconnectivitySwitch.Value = '4';
                PropertiescalcificationButtonPushed(app);
    
                %carbonate content
                app.ThresholdRsquaredSwitch.Value = 'Automatic';
                VisualisecarbonatecontentButtonPushed(app);
                PropertiescalcificationButton_2Pushed(app);
    
                %protein content
                VisiualiseproteincontentButtonPushed(app);
                PropertiescalcificationButton_4Pushed(app);
    
                %crystallinity + Magnesium
                VisualisecrystallinitymagnesiumcontentButtonPushed(app);
                PropertiescalcificationButton_3Pushed(app);

                complete_data = [app.UITable.Data app.UITable2.Data(:,2:end) app.UITable4.Data(:,2:end) app.UITable3.Data(:,2:end)];
                patient = repmat({Files(i).name},size(complete_data,1),1);
                complete_data = addvars(complete_data, patient,'Before', 1);

                Results_allfiles = [Results_allfiles; complete_data];
                disp('file:')
                disp(m);
                m = m+1;
            end

      
            [filepath,foldername] = fileparts(folderPath);
            Excelname = "Totalresults_patient_" + foldername + ".xlsx";
            [filename, folder] = uiputfile(Excelname);
            writetable(Results_allfiles,fullfile(folder,filename),'sheet','Result')        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UploadFilterTab = uitab(app.TabGroup);
            app.UploadFilterTab.Title = 'Upload + Filter';
            app.UIAxes4 = uiaxes(app.UploadFilterTab);
            title(app.UIAxes4, 'Unfiltered Average Raman Spectrum')
            xlabel(app.UIAxes4, 'Raman shift (cm^{-1})')
            ylabel(app.UIAxes4, 'Intensity')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4_2 = uiaxes(app.UploadFilterTab);
            title(app.UIAxes4_2, 'Filtered Average Raman Spectrum')
            xlabel(app.UIAxes4_2, 'Raman shift (cm^{-1})')
            ylabel(app.UIAxes4_2, 'Intensity')
            zlabel(app.UIAxes4_2, 'Z')
            app.UploadRamanDataButton = uibutton(app.UploadFilterTab, 'push');
            app.UploadRamanDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadRamanDataButtonPushed, true);
            app.UploadRamanDataButton.Text = 'Upload Raman Data';
            app.LowerBoundEditFieldLabel = uilabel(app.UploadFilterTab);
            app.LowerBoundEditFieldLabel.Text = 'Lower Bound';
            app.LowerBoundEditField = uieditfield(app.UploadFilterTab, 'numeric');
            app.LowerBoundEditField.Value = 400;
            app.BaselineCorrectionCheckBox = uicheckbox(app.UploadFilterTab);
            app.BaselineCorrectionCheckBox.Text = 'Baseline Correction';
            app.TruncationofSpectrumCheckBox = uicheckbox(app.UploadFilterTab);
            app.TruncationofSpectrumCheckBox.Text = 'Truncation of Spectrum';
            app.UpperBoundEditFieldLabel = uilabel(app.UploadFilterTab);
            app.UpperBoundEditFieldLabel.Text = 'Upper Bound';
            app.UpperBoundEditField = uieditfield(app.UploadFilterTab, 'numeric');
            app.UpperBoundEditField.Value = 3000;
            app.PreprocessingButton = uibutton(app.UploadFilterTab, 'push');
            app.PreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingButtonPushed, true);
            app.PreprocessingButton.Text = 'Preprocessing';
            app.NormalizationbeforesubstractingpropertiesCheckBox = uicheckbox(app.UploadFilterTab);
            app.NormalizationbeforesubstractingpropertiesCheckBox.Text = 'Normalization (before substracting properties)';
            app.pEditFieldLabel = uilabel(app.UploadFilterTab);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.UploadFilterTab, 'numeric');
            app.pEditField.Value = 0.02;
            app.yEditField_2Label = uilabel(app.UploadFilterTab);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.UploadFilterTab, 'numeric');
            app.yEditField_2.Value = 100000;
            app.SelectpreprocessingoptionsLabel = uilabel(app.UploadFilterTab);
            app.SelectpreprocessingoptionsLabel.Text = 'Select preprocessing options:';
            app.NormalizationaftersubstractingpropertiesCheckBox = uicheckbox(app.UploadFilterTab);
            app.NormalizationaftersubstractingpropertiesCheckBox.Text = 'Normalization (after substracting properties)';
            app.PreprocessingcompletedLampLabel = uilabel(app.UploadFilterTab);
            app.PreprocessingcompletedLampLabel.Text = 'Preprocessing completed:';
            app.PreprocessingcompletedLamp = uilamp(app.UploadFilterTab);
            app.OverviewspectraTab = uitab(app.TabGroup);
            app.OverviewspectraTab.Title = 'Overview spectra';
            app.UIAxes_2 = uiaxes(app.OverviewspectraTab);
            title(app.UIAxes_2, 'Intensity map (960 peak)')
            xlabel(app.UIAxes_2, 'um')
            ylabel(app.UIAxes_2, 'um')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            colormap(app.UIAxes_2, 'hot')
            app.UIAxes3 = uiaxes(app.OverviewspectraTab);
            title(app.UIAxes3, 'Raman spectrum of selected pixel')
            xlabel(app.UIAxes3, 'Raman shift (cm^{-1})')
            ylabel(app.UIAxes3, 'Intensity')
            zlabel(app.UIAxes3, 'Z')
            app.PlotIntensityButton = uibutton(app.OverviewspectraTab, 'push');
            app.PlotIntensityButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIntensitymap960peakButtonPushed, true);
            app.PlotIntensityButton.Text = 'Plot Intensity';
            app.xEditFieldLabel = uilabel(app.OverviewspectraTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.OverviewspectraTab, 'numeric');
            app.yEditFieldLabel = uilabel(app.OverviewspectraTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.OverviewspectraTab, 'numeric');
            app.Switch = uiswitch(app.OverviewspectraTab, 'slider');
            app.Switch.Items = {'Filtered', 'Raw'};
            app.Switch.Value = 'Filtered';
            app.ThresholdLBEditFieldLabel = uilabel(app.OverviewspectraTab);
            app.ThresholdLBEditFieldLabel.Text = 'Threshold LB';
            app.ThresholdLBEditField = uieditfield(app.OverviewspectraTab, 'numeric');
            app.ThresholdLBEditField.Value = 955;
            app.ThresholdUBEditFieldLabel = uilabel(app.OverviewspectraTab);
            app.ThresholdUBEditFieldLabel.Text = 'Threshold UB';
            app.ThresholdUBEditField = uieditfield(app.OverviewspectraTab, 'numeric');
            app.ThresholdUBEditField.Value = 965;
            app.PixelcoordinatesinpixLabel = uilabel(app.OverviewspectraTab);
            app.PixelcoordinatesinpixLabel.Text = 'Pixel coordinates (in pix:)';
            app.SizeShapeTab = uitab(app.TabGroup);
            app.SizeShapeTab.Title = 'Size + Shape';
            app.UIAxes = uiaxes(app.SizeShapeTab);
            title(app.UIAxes, 'Intensity map (960 peak)')
            xlabel(app.UIAxes, 'um')
            ylabel(app.UIAxes, 'um')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'hot')
            app.UIAxes2 = uiaxes(app.SizeShapeTab);
            title(app.UIAxes2, 'Binary map (960 peak)')
            xlabel(app.UIAxes2, 'um')
            ylabel(app.UIAxes2, 'um')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'gray')
            app.PlotIntensitymap960peakButton = uibutton(app.SizeShapeTab, 'push');
            app.PlotIntensitymap960peakButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIntensitymap960peakButtonPushed, true);
            app.PlotIntensitymap960peakButton.Text = {'Plot Intensity map '; '(960 peak)'};
            app.PlotBinarymapButton = uibutton(app.SizeShapeTab, 'push');
            app.PlotBinarymapButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBinarymapButtonPushed, true);
            app.PlotBinarymapButton.Text = 'Plot Binary map';
            app.UITable = uitable(app.SizeShapeTab);
            app.UITable.ColumnName = {'Index'; 'Area (pix.)'; 'Area (um)'; 'Circularity'; 'Perimeter'; 'Fourier Descriptor'; 'Centroid dist. var.'; 'ratio max/mn dist.'};
            app.UITable.RowName = {};
            app.PropertiescalcificationButton = uibutton(app.SizeShapeTab, 'push');
            app.PropertiescalcificationButton.ButtonPushedFcn = createCallbackFcn(app, @PropertiescalcificationButtonPushed, true);
            app.PropertiescalcificationButton.Text = 'Properties calcification';
            app.ThresholdEditField = uieditfield(app.SizeShapeTab, 'numeric');
            app.ThresholdEditField.Value = 215;
            app.PixelconnectivitySwitchLabel = uilabel(app.SizeShapeTab);
            app.PixelconnectivitySwitchLabel.Text = 'Pixel connectivity';
            app.PixelconnectivitySwitch = uiswitch(app.SizeShapeTab, 'slider');
            app.PixelconnectivitySwitch.Items = {'4', '8'};
            app.PixelconnectivitySwitch.Value = '4';
            app.ThresholdSwitchLabel = uilabel(app.SizeShapeTab);
            app.ThresholdSwitchLabel.Text = 'Threshold';
            app.ThresholdSwitch = uiswitch(app.SizeShapeTab, 'slider');
            app.ThresholdSwitch.Items = {'Manual', 'Automatic'};
            app.ThresholdSwitch.Value = 'Automatic';
            app.EditField = uieditfield(app.SizeShapeTab, 'numeric');
            app.PlotsegmentedbinarymapButton = uibutton(app.SizeShapeTab, 'push');
            app.PlotsegmentedbinarymapButton.ButtonPushedFcn = createCallbackFcn(app, @PlotsegmentedbinarymapButtonPushed, true);
            app.PlotsegmentedbinarymapButton.Text = 'Plot segmented binary map';
            app.SizeandshapecompletedLampLabel = uilabel(app.SizeShapeTab);
            app.SizeandshapecompletedLampLabel.Text = 'Size and shape completed:';
            app.SizeandshapecompletedLamp = uilamp(app.SizeShapeTab);
            app.CarbonateContentTab = uitab(app.TabGroup);
            app.CarbonateContentTab.Title = 'Carbonate Content';
            app.UIAxes5 = uiaxes(app.CarbonateContentTab);
            title(app.UIAxes5, 'Heatmap')
            xlabel(app.UIAxes5, 'um')
            ylabel(app.UIAxes5, 'um')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.ButtonDownFcn = createCallbackFcn(app, @UIAxes5ButtonDown, true);
            colormap(app.UIAxes5, 'gray')
            app.UIAxes6 = uiaxes(app.CarbonateContentTab);
            title(app.UIAxes6, 'Corresponding 960 spectra per pixel')
            xlabel(app.UIAxes6, 'Raman shift cm^{-1}')
            ylabel(app.UIAxes6, 'Intensity')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.CarbonateContentTab);
            title(app.UIAxes7, 'Corresponding 1070 spectra per pixel')
            xlabel(app.UIAxes7, 'Raman shift cm^{-1}')
            ylabel(app.UIAxes7, 'Intensity')
            zlabel(app.UIAxes7, 'Z')
            app.CarbonatevisualisationinheatmapsButtonGroup = uibuttongroup(app.CarbonateContentTab);
            app.CarbonatevisualisationinheatmapsButtonGroup.Title = 'Carbonate visualisation in heatmaps';
            app.BinarymapButton = uiradiobutton(app.CarbonatevisualisationinheatmapsButtonGroup);
            app.BinarymapButton.Text = 'Binary map';
            app.BinarymapButton.Value = true;
            app.FWHM960lorentzianfitButton = uiradiobutton(app.CarbonatevisualisationinheatmapsButtonGroup);
            app.FWHM960lorentzianfitButton.Text = 'FWHM 960 (lorentzian fit)';
            app.Area960peakButton = uiradiobutton(app.CarbonatevisualisationinheatmapsButtonGroup);
            app.Area960peakButton.Text = 'Area 960 peak';
            app.SwitchvisualisationButton_3 = uibutton(app.CarbonatevisualisationinheatmapsButtonGroup, 'push');
            app.SwitchvisualisationButton_3.ButtonPushedFcn = createCallbackFcn(app, @SwitchvisualisationButton_3Pushed, true);
            app.SwitchvisualisationButton_3.Text = 'Switch visualisation';
            app.Intensity960peakButton = uiradiobutton(app.CarbonatevisualisationinheatmapsButtonGroup);
            app.Intensity960peakButton.Text = 'Intensity 960 peak';
            app.Area1070peakButton = uiradiobutton(app.CarbonatevisualisationinheatmapsButtonGroup);
            app.Area1070peakButton.Text = 'Area 1070 peak';
            app.Intensity1070peakButton = uiradiobutton(app.CarbonatevisualisationinheatmapsButtonGroup);
            app.Intensity1070peakButton.Text = 'Intensity 1070 peak';
            app.Ratioarea1070960Button = uiradiobutton(app.CarbonatevisualisationinheatmapsButtonGroup);
            app.Ratioarea1070960Button.Text = 'Ratio area 1070/960 ';
            app.Ratiointensity1070960Button = uiradiobutton(app.CarbonatevisualisationinheatmapsButtonGroup);
            app.Ratiointensity1070960Button.Text = 'Ratio intensity 1070/960 ';
            app.normresidual1070Button = uiradiobutton(app.CarbonatevisualisationinheatmapsButtonGroup);
            app.normresidual1070Button.Text = 'norm residual 1070';
            app.Rsquared1070Button = uiradiobutton(app.CarbonatevisualisationinheatmapsButtonGroup);
            app.Rsquared1070Button.Text = 'R squared 1070';
            app.corarea1070peakButton = uiradiobutton(app.CarbonatevisualisationinheatmapsButtonGroup);
            app.corarea1070peakButton.Text = 'cor area 1070 peak';
            app.corIntensity1070peakButton = uiradiobutton(app.CarbonatevisualisationinheatmapsButtonGroup);
            app.corIntensity1070peakButton.Text = 'cor Intensity 1070 peak';
            app.ThresholdRsquaredEditField = uieditfield(app.CarbonatevisualisationinheatmapsButtonGroup, 'numeric');
            app.ThresholdRsquaredEditField.Value = 0.35;
            app.ThresholdRsquaredSwitchLabel = uilabel(app.CarbonatevisualisationinheatmapsButtonGroup);
            app.ThresholdRsquaredSwitchLabel.Text = 'Threshold Rsquared';
            app.ThresholdRsquaredSwitch = uiswitch(app.CarbonatevisualisationinheatmapsButtonGroup, 'slider');
            app.ThresholdRsquaredSwitch.Items = {'Manual', 'Automatic'};
            app.ThresholdRsquaredSwitch.Value = 'Automatic';
            app.EditField_2 = uieditfield(app.CarbonatevisualisationinheatmapsButtonGroup, 'numeric');
            app.FWHM960manuallyButton = uiradiobutton(app.CarbonatevisualisationinheatmapsButtonGroup);
            app.FWHM960manuallyButton.Text = 'FWHM 960 (manually)';
            app.VisualisecarbonatecontentButton = uibutton(app.CarbonateContentTab, 'push');
            app.VisualisecarbonatecontentButton.ButtonPushedFcn = createCallbackFcn(app, @VisualisecarbonatecontentButtonPushed, true);
            app.VisualisecarbonatecontentButton.Text = 'Visualise carbonate content';
            app.UITable2 = uitable(app.CarbonateContentTab);
            app.UITable2.ColumnName = {'Index'; 'FWHM 960 (avg.)'; 'FWHM 960 (std.)'; 'FWHM 960 hand (avg.)'; 'FWHM 960 hand (std.)'; 'Area 960 (avg.)'; 'Area 960 (std.)'; 'Intensity 960 (avg.)'; 'Intensity 960 (std.)'; 'Area 1070 (avg.)'; 'Area 1070 (std.)'; 'Intensity 1070 (avg.)'; 'Intensity 1070 (std.)'; 'Ratio area 1070/960 (avg.)'; 'Ratio area 1070/960 (std.)'; 'Ratio intensity 1070/960 (avg)'; 'Ratio intensity 1070/960 (std.)'};
            app.UITable2.RowName = {};
            app.CarbonatecontentcompletedLamp_2Label = uilabel(app.CarbonateContentTab);
            app.CarbonatecontentcompletedLamp_2Label.Text = 'Carbonate content completed:';
            app.CarbonatecontentcompletedLamp_2 = uilamp(app.CarbonateContentTab);
            app.PropertiescalcificationButton_2 = uibutton(app.CarbonateContentTab, 'push');
            app.PropertiescalcificationButton_2.ButtonPushedFcn = createCallbackFcn(app, @PropertiescalcificationButton_2Pushed, true);
            app.PropertiescalcificationButton_2.Text = 'Properties calcification';
            app.ProteinContentTab = uitab(app.TabGroup);
            app.ProteinContentTab.Title = 'Protein Content';
            app.UIAxes10 = uiaxes(app.ProteinContentTab);
            title(app.UIAxes10, 'Background corrected spectrum of selected pixel')
            xlabel(app.UIAxes10, 'Raman shift cm^{-1}')
            ylabel(app.UIAxes10, 'Intensity')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes11 = uiaxes(app.ProteinContentTab);
            title(app.UIAxes11, 'Heatmap')
            xlabel(app.UIAxes11, 'um')
            ylabel(app.UIAxes11, 'um')
            zlabel(app.UIAxes11, 'Z')
            app.UIAxes11.ButtonDownFcn = createCallbackFcn(app, @UIAxes11ButtonDown, true);
            app.UIAxes12 = uiaxes(app.ProteinContentTab);
            title(app.UIAxes12, '1004 peak')
            xlabel(app.UIAxes12, 'Raman shift cm^{-1}')
            ylabel(app.UIAxes12, 'Intensity')
            zlabel(app.UIAxes12, 'Z')
            app.UIAxes12_2 = uiaxes(app.ProteinContentTab);
            title(app.UIAxes12_2, '1445 peak')
            xlabel(app.UIAxes12_2, 'Raman shift cm^{-1}')
            ylabel(app.UIAxes12_2, 'Intensity')
            zlabel(app.UIAxes12_2, 'Z')
            app.UIAxes12_3 = uiaxes(app.ProteinContentTab);
            title(app.UIAxes12_3, '1650 peak')
            xlabel(app.UIAxes12_3, 'Raman shift cm^{-1}')
            ylabel(app.UIAxes12_3, 'Intensity')
            zlabel(app.UIAxes12_3, 'Z')
            app.VisiualiseproteincontentButton = uibutton(app.ProteinContentTab, 'push');
            app.VisiualiseproteincontentButton.ButtonPushedFcn = createCallbackFcn(app, @VisiualiseproteincontentButtonPushed, true);
            app.VisiualiseproteincontentButton.Text = 'Visiualise protein content';
            app.ProteincontentcompletedLampLabel = uilabel(app.ProteinContentTab);
            app.ProteincontentcompletedLampLabel.Text = 'Protein content completed: ';
            app.ProteincontentcompletedLamp = uilamp(app.ProteinContentTab);
            app.ProteinvisualisationinheatmapsButtonGroup = uibuttongroup(app.ProteinContentTab);
            app.ProteinvisualisationinheatmapsButtonGroup.Title = 'Protein visualisation in heatmaps';
            app.area1004Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.area1004Button.Text = 'area 1004';
            app.area1004Button.Value = true;
            app.intensity1004Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.intensity1004Button.Text = 'intensity 1004';
            app.SwitchvisualisationButton_2 = uibutton(app.ProteinvisualisationinheatmapsButtonGroup, 'push');
            app.SwitchvisualisationButton_2.ButtonPushedFcn = createCallbackFcn(app, @SwitchvisualisationButton_2Pushed, true);
            app.SwitchvisualisationButton_2.Text = 'Switch visualisation';
            app.resnorm1004Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.resnorm1004Button.Text = 'resnorm 1004';
            app.rsquared1004Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.rsquared1004Button.Text = 'r squared 1004';
            app.ThresEditFieldLabel = uilabel(app.ProteinvisualisationinheatmapsButtonGroup);
            app.ThresEditFieldLabel.Text = 'Thres:';
            app.ThresEditField = uieditfield(app.ProteinvisualisationinheatmapsButtonGroup, 'numeric');
            app.binary1004Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.binary1004Button.Text = 'binary 1004';
            app.corarea1004Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.corarea1004Button.Text = 'cor area 1004';
            app.corintensity1004Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.corintensity1004Button.Text = 'cor intensity 1004';
            app.area1445Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.area1445Button.Text = 'area 1445';
            app.intensity1445Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.intensity1445Button.Text = 'intensity 1445';
            app.resnorm1445Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.resnorm1445Button.Text = 'resnorm 1445';
            app.rsquared1445Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.rsquared1445Button.Text = 'r squared 1445';
            app.binary1445Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.binary1445Button.Text = 'binary 1445';
            app.corarea1445Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.corarea1445Button.Text = 'cor area 1445';
            app.corintensity1445Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.corintensity1445Button.Text = 'cor intensity 1445';
            app.ThresEditField_2Label = uilabel(app.ProteinvisualisationinheatmapsButtonGroup);
            app.ThresEditField_2Label.Text = 'Thres:';
            app.ThresEditField_2 = uieditfield(app.ProteinvisualisationinheatmapsButtonGroup, 'numeric');
            app.area1650Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.area1650Button.Text = 'area 1650';
            app.intensity1650Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.intensity1650Button.Text = 'intensity 1650';
            app.resnorm1650Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.resnorm1650Button.Text = 'resnorm 1650';
            app.rsquared1650Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.rsquared1650Button.Text = 'r squared 1650';
            app.binary1650Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.binary1650Button.Text = 'binary 1650';
            app.corarea1650Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.corarea1650Button.Text = 'cor area 1650';
            app.corintensity1650Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.corintensity1650Button.Text = 'cor intensity 1650';
            app.ThresEditField_3Label = uilabel(app.ProteinvisualisationinheatmapsButtonGroup);
            app.ThresEditField_3Label.Text = 'Thres:';
            app.ThresEditField_3 = uieditfield(app.ProteinvisualisationinheatmapsButtonGroup, 'numeric');
            app.ratiophe1HapButton = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.ratiophe1HapButton.Text = 'ratio phe1/Hap';
            app.ratioCO32phe1Button = uiradiobutton(app.ProteinvisualisationinheatmapsButtonGroup);
            app.ratioCO32phe1Button.Text = 'ratio CO32/phe1';
            app.PropertiescalcificationButton_4 = uibutton(app.ProteinContentTab, 'push');
            app.PropertiescalcificationButton_4.ButtonPushedFcn = createCallbackFcn(app, @PropertiescalcificationButton_4Pushed, true);
            app.PropertiescalcificationButton_4.Text = 'Properties calcification';
            app.UITable4 = uitable(app.ProteinContentTab);
            app.UITable4.ColumnName = {'Index'; 'A 1004 (avg.)'; 'A 1004 (std.)'; 'I 1004 (avg.)'; 'I 1004 (std.)'; 'A 1445 (avg.)'; 'A 1445 (std.)'; 'I 1445 (avg.)'; 'I 1445 (std.)'; 'A 1650 (avg.)'; 'A 1650 (std.)'; 'I 1650 (avg.)'; 'I 1650 (std.)'; 'ratio phe1/hap (avg)'; 'ratio phe1/hap (std.)'; 'ratio CO32/phe1 (avg.)'; 'ratio CO32/phe1 (std.)'};
            app.UITable4.RowName = {};
            app.CrystallinityMagnesiumContentTab = uitab(app.TabGroup);
            app.CrystallinityMagnesiumContentTab.Title = 'Crystallinity + Magnesium Content';
            app.UIAxes8 = uiaxes(app.CrystallinityMagnesiumContentTab);
            title(app.UIAxes8, 'Heatmap')
            xlabel(app.UIAxes8, 'um')
            ylabel(app.UIAxes8, 'um')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes8.ButtonDownFcn = createCallbackFcn(app, @UIAxes8ButtonDown, true);
            colormap(app.UIAxes8, 'gray')
            app.UIAxes9 = uiaxes(app.CrystallinityMagnesiumContentTab);
            title(app.UIAxes9, 'Corresponding 960 peak per pixel')
            xlabel(app.UIAxes9, 'Raman shift (cm^{-1})')
            ylabel(app.UIAxes9, 'Intensity')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes9_2 = uiaxes(app.CrystallinityMagnesiumContentTab);
            title(app.UIAxes9_2, 'Corresponding 1070 peak per pixel')
            xlabel(app.UIAxes9_2, 'Raman shift (cm^{-1})')
            ylabel(app.UIAxes9_2, 'Intensity')
            zlabel(app.UIAxes9_2, 'Z')
            app.CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup = uibuttongroup(app.CrystallinityMagnesiumContentTab);
            app.CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup.Title = 'Crystallinity and magnesium visualisation in heatmaps';
            app.InverseFWHM960LorentzianfitButton = uiradiobutton(app.CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup);
            app.InverseFWHM960LorentzianfitButton.Text = 'Inverse FWHM 960 (Lorentzian fit)';
            app.InverseFWHM960LorentzianfitButton.Value = true;
            app.XcoordinatephosphateButton = uiradiobutton(app.CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup);
            app.XcoordinatephosphateButton.Text = 'X coordinate phosphate';
            app.ShiftphosphatepeakButton = uiradiobutton(app.CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup);
            app.ShiftphosphatepeakButton.Text = 'Shift phosphate peak';
            app.WvnPhosphateEditFieldLabel = uilabel(app.CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup);
            app.WvnPhosphateEditFieldLabel.Text = 'Wvn Phosphate';
            app.WvnPhosphateEditField = uieditfield(app.CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup, 'numeric');
            app.WvnPhosphateEditField.Value = 960;
            app.XcoordinatecarbonateButton = uiradiobutton(app.CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup);
            app.XcoordinatecarbonateButton.Text = 'X coordinate carbonate';
            app.ShiftcarbonatepeakButton = uiradiobutton(app.CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup);
            app.ShiftcarbonatepeakButton.Text = 'Shift carbonate peak';
            app.corrXcoordinatecarbonateButton = uiradiobutton(app.CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup);
            app.corrXcoordinatecarbonateButton.Text = 'corr X coordinate carbonate';
            app.corrShiftcarbonatepeakButton = uiradiobutton(app.CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup);
            app.corrShiftcarbonatepeakButton.Text = 'corr Shift carbonate peak';
            app.WvnCarbonateEditFieldLabel = uilabel(app.CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup);
            app.WvnCarbonateEditFieldLabel.Text = 'Wvn Carbonate';
            app.WvnCarbonateEditField = uieditfield(app.CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup, 'numeric');
            app.WvnCarbonateEditField.Value = 1070;
            app.InverseFWHM960manuallyButton = uiradiobutton(app.CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup);
            app.InverseFWHM960manuallyButton.Text = 'Inverse FWHM 960 (manually)';
            app.SwitchvisualisationButton = uibutton(app.CrystallinityandmagnesiumvisualisationinheatmapsButtonGroup, 'push');
            app.SwitchvisualisationButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchvisualisationButtonPushed, true);
            app.SwitchvisualisationButton.Text = 'Switch visualisation';
            app.VisualisecrystallinitymagnesiumcontentButton = uibutton(app.CrystallinityMagnesiumContentTab, 'push');
            app.VisualisecrystallinitymagnesiumcontentButton.ButtonPushedFcn = createCallbackFcn(app, @VisualisecrystallinitymagnesiumcontentButtonPushed, true);
            app.VisualisecrystallinitymagnesiumcontentButton.Text = 'Visualise crystallinity/magnesium content';
            app.PropertiescalcificationButton_3 = uibutton(app.CrystallinityMagnesiumContentTab, 'push');
            app.PropertiescalcificationButton_3.ButtonPushedFcn = createCallbackFcn(app, @PropertiescalcificationButton_3Pushed, true);
            app.PropertiescalcificationButton_3.Text = 'Properties calcification';
            app.UITable3 = uitable(app.CrystallinityMagnesiumContentTab);
            app.UITable3.ColumnName = {'Index'; 'Inv FWHM (avg.)'; 'Inv FWHM (std.)'; 'Inv FWHM hand (avg.)'; 'Inv FWHM hand (std.)'; 'X 960 (avg.)'; 'X 960 (std.)'; 'Shift 960 (avg.)'; 'Shift 960 (std.)'; 'X 1070 (avg.)'; 'X 1070(std.)'; 'Shift 1070(avg.)'; 'Shift 1070(std.)'};
            app.UITable3.RowName = {};
            app.CrystallintymagnsiumcontentcompletedLabel = uilabel(app.CrystallinityMagnesiumContentTab);
            app.CrystallintymagnsiumcontentcompletedLabel.Text = 'Crystallinty magnsium content completed: ';
            app.CrystallintymagnsiumcontentcompletedLamp = uilamp(app.CrystallinityMagnesiumContentTab);
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ExportpropertiesofsinglefileButton = uibutton(app.ExportTab, 'push');
            app.ExportpropertiesofsinglefileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportpropertiesofsinglefileButtonPushed, true);
            app.ExportpropertiesofsinglefileButton.Text = 'Export properties of single file';
            app.ExportpropertiesofpatientfolderButton = uibutton(app.ExportTab, 'push');
            app.ExportpropertiesofpatientfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ExportpropertiesofpatientfolderButtonPushed, true);
            app.ExportpropertiesofpatientfolderButton.Text = 'Export properties of patient folder';
            app.PipelinenotmanuallyselectingpropertiesLabel = uilabel(app.ExportTab);
            app.PipelinenotmanuallyselectingpropertiesLabel.Text = 'When properties of calcification are extracted by automatic pipeline:';
            app.WhenPropertiescalcificationareallselectedbyhandLabel = uilabel(app.ExportTab);
            app.WhenPropertiescalcificationareallselectedbyhandLabel.Text = 'When ''Properties calcification'' are all selected by hand: ';
            app.ExportpropertiesmanuallyButton = uibutton(app.ExportTab, 'push');
            app.ExportpropertiesmanuallyButton.ButtonPushedFcn = createCallbackFcn(app, @ExportpropertiesmanuallyButtonPushed, true);
            app.ExportpropertiesmanuallyButton.Text = 'Export properties (manually)';
            app.OnefileLabel = uilabel(app.ExportTab);
            app.OnefileLabel.Text = 'One file:';
            app.MultiplefilesinonefolderLabel = uilabel(app.ExportTab);
            app.MultiplefilesinonefolderLabel.Text = 'Multiple files in one folder:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FeatureExtractionPipeline

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end